classdef ArtifactCorrectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.EditField
        UIAxes2                         matlab.ui.control.UIAxes
        NearestDataPointEditFieldLabel  matlab.ui.control.Label
        NearestDataPointEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        currentpoint % Description
        artifactlist
        time % Description
        data % Description
        selectLine % Description
        selectedArtifact % Description
    end
    
    methods (Access = public)
        
        function results = getnearestdatapoint(app,x,y)
            for i=1:length(app.time)
                xdist = abs(x-app.time(i));
                
                if ~exist('dist', 'var')
                    dist = xdist;
                else
                    if (xdist > dist)
                        i=i-1;
                        break;
                    else
                        dist = xdist;
                    end
                end
            end
            results = i;
        end


        function drawArtifacts(app)
            for i=1:length(app.artifactlist)
                thisArtifact = app.artifactlist(i,:);
                drawArtifact(app,thisArtifact(1),thisArtifact(2));
            end
        end
        
        function drawArtifact(app,tStart,tEnd)
            pos = [tStart 0 tEnd-tStart max(app.data)];
            rect = rectangle(app.UIAxes, 'Position', pos, 'FaceColor', 'r', 'EdgeColor', 'r','Tag', 'artifact');
        end
        
        function drawBackgroupd(app)
            pos = [0 0 max(app.time) max(app.data)];
            rect = rectangle(app.UIAxes, 'Position', pos, 'FaceColor', 'y', 'EdgeColor', 'y','Tag', 'background');
        end
        
        function drawData(app)
            hold(app.UIAxes, 'off');
            cla(app.UIAxes);
            
            hold(app.UIAxes, 'on');
            drawBackgroupd(app);
            drawArtifacts(app);
            
            plot(app.UIAxes,app.time, app.data);
        end
        
        
        function drawSelectLine(app,time)
            app.removeSelectLine();
            app.selectLine = xline(app.UIAxes,time,'--b','LineWidth', 3);
        end
        
        function removeSelectLine(app)
            delete(app.selectLine);
        end
        
        function result = isOnArtifactEdge(app,time)
            artifactsize = size(app.artifactlist);
            
            for x = 1: artifactsize(1)
                for y = 1: artifactsize(2)
                    if time == app.artifactlist(x,y)
                        result = 1;
                        return
                    end
                end
            end
            result = 0;
        end
        
        
        function result = getArtifactEdgeLocation(app,time)
            artifactsize = size(app.artifactlist);
            
            for x = 1: artifactsize(1)
                for y = 1: artifactsize(2)
                    if time == app.artifactlist(x,y)
                        result.x = x;
                        result.y = y;
                        return
                    end
                end
            end
        end
        
        
        function checkSelectPosition(app,time,trigger)
            if isOnArtifactEdge(app,time)
                location = getArtifactEdgeLocation(app,time)
                
                if app.selectedArtifact.x == 0
                    enableArtifactSelection(app,time,location);
                else
                    if isequaln(app.selectedArtifact,location)
                        disableArtifactSelection(app);
                    else
                        disableArtifactSelection(app);
                        enableArtifactSelection(app,time,location);
                    end
                end
            elseif trigger == "artifact"
                if app.selectedArtifact.x ~= 0
                    if isTimeInCurrentArtifact(app,time)
                        updateSelectedArtifact(app,time);
                    end
                end
            elseif trigger == "background"
                if app.selectedArtifact.x ~= 0
                    if isTimeBeforeNextArtifact(app,time)
                        updateSelectedArtifact(app,time);
                    end
                end
            else
                
            end
        end
        
        function result = isTimeInCurrentArtifact(app, time)
            if (time > app.artifactlist(app.selectedArtifact.x,1) && time < app.artifactlist(app.selectedArtifact.x,2))
                result = true;
            else
                result = false;
            end
        end
        
        function result = isTimeBeforeNextArtifact(app, time)
            result = false;
            if app.selectedArtifact.y == 1
                if time < app.artifactlist(app.selectedArtifact.x,1)
                    if app.selectedArtifact.x == 1
                        result = true;
                    elseif time > app.artifactlist(app.selectedArtifact.x-1,2)
                        result = true;
                    end
                end
            elseif app.selectedArtifact.y == 2
                if time > app.artifactlist(app.selectedArtifact.x,2)
                    if app.selectedArtifact.x == size(app.artifactlist,1)
                        result = true;
                    elseif time < app.artifactlist(app.selectedArtifact.x+1,1)
                        result = true;
                    end
                end
            end
        end
        
        function updateSelectedArtifact(app,time)
            if app.selectedArtifact.y == 1
                changeArtifactStart(app,time,app.selectedArtifact.x);
            elseif app.selectedArtifact.y == 2
                changeArtifactEnd(app,time,app.selectedArtifact.x);
            end
            
            app.artifactlist
            disableArtifactSelection(app);
            drawData(app);
        end
        
        function changeArtifactStart(app,time,artifact)
            app.artifactlist(artifact,1) = time;
        end
        
        function changeArtifactEnd(app,time,artifact)
             app.artifactlist(artifact,2) = time;
        end
        
        
        function result = isArtifactStart(app,time)
            artifactsize = size(app.artifactlist);
            
            for x = 1: artifactsize(1)
                if time == app.artifactlist(x,1)
                    result = 1;
                    return
                end
            end
            result = 0;
        end
        
        function result = isArtifactEnd(app,time)
            artifactsize = size(app.artifactlist);
            
            for x = 1: artifactsize(1)
                if time == app.artifactlist(x,2)
                    result = 1;
                    return
                end
            end
            result = 0;
        end
        
        function disableArtifactSelection(app)
            app.removeSelectLine();
            app.selectedArtifact.x = 0;
            app.selectedArtifact.y = 0;
        end
        
        function enableArtifactSelection(app,time,location)
            app.drawSelectLine(time);
            app.selectedArtifact.x = location.x;
            app.selectedArtifact.y = location.y;
        end
        
        function setDebugData(app,x,y,nearestdatapoint)
            app.xEditField.Value = num2str(x);
            app.yEditField.Value = num2str(y);
            
            alpha = 10;
            app.UIAxes2.XLim = [(app.currentpoint-alpha) (app.currentpoint+alpha)];
            
            plot(app.UIAxes2,app.time)
            
            app.NearestDataPointEditField.Value = num2str(nearestdatapoint);
        end
        
        function position = getClickPosition(app)
            app.currentpoint = app.UIAxes.CurrentPoint(1);
            app.Label.Text = num2str(app.currentpoint);
            
            position.x = app.UIAxes.CurrentPoint(1);
            position.y = app.UIAxes.CurrentPoint(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.time = [0,0.25,0.5,0.75,1,1.25,1.5,1.75,2,2.25,2.5,2.75,3,3.25,3.5,3.75,4,4.25,4.5,4.75];
        app.data = [5,2,7,9,4,2,5,7,8,9,4,2,1,4,6,8,0,5,3,2];
        app.artifactlist = [0.75 1.75 ; 3 4];
        app.selectedArtifact.x = 0;
        app.selectedArtifact.y = 0;
        
        drawData(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
        
        if ~isempty(event.Source.CurrentObject)
            if isequal(event.Source.CurrentObject.Tag, 'artifact')||isequal(event.Source.CurrentObject.Tag, 'background')
                %% Determine click position
                position = getClickPosition(app);
                
                % Get nearest position of data
                nearestdatapoint = app.getnearestdatapoint(position.x,position.y);
                
                %% Debug selected data
                setDebugData(app,position.x,position.y,nearestdatapoint);
                %%
                
                %% Trigger logic for checking the click position
                app.checkSelectPosition(app.time(nearestdatapoint),convertCharsToStrings(event.Source.CurrentObject.Tag));
            end
        else
            %% If you click outside of the graph area, then disable artifact selection moving completely
            disableArtifactSelection(app);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.NearestDataPointEditFieldLabel = uilabel(app.UIFigure);
            app.NearestDataPointEditFieldLabel.Text = 'NearestDataPoint';
            app.NearestDataPointEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArtifactCorrectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end