classdef ArtifactCorrectionAppAddWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AcceptButton         matlab.ui.control.Button
        ArtifactEndValue     matlab.ui.control.NumericEditField
        EndEditFieldLabel    matlab.ui.control.Label
        ArtifactStartValue   matlab.ui.control.NumericEditField
        StartEditFieldLabel  matlab.ui.control.Label
        CancelButton         matlab.ui.control.Button
        DataAxes             matlab.ui.control.UIAxes
    end


    properties (Access = public)
        artifacts
        starttime
        endtime
        data
        mainapp
        time
        artifactobjects
        dataplot
        newartifact
    end

    methods (Access = public)

        %% INTERACTIONS
        function UpdateStartTime(app)
            app.starttime = app.ArtifactStartValue.Value;
            app.PlotNewArtifact();
        end
        
        function UpdateEndTime(app)
            app.endtime = app.ArtifactEndValue.Value;
            app.PlotNewArtifact();
        end
        
        function ReturnNewArtifact(app)
            ReturnNewArtifactAction(app.mainapp,app.starttime,app.endtime);
            delete(app)
        end

        function CancelAddArtifact(app)
            CancelNewArtifactAction(app.mainapp);
            delete(app)
        end
        
        %% VISUALS
        function PlotOriginalData(app)
            app.dataplot = plot(app.DataAxes,app.time,app.data);
        end

        function PlotOriginalArtifacts(app)
            for i=1:length(app.artifacts)
                startpos = app.artifacts(i).start;
                endpos = app.artifacts(i).end;
                app.artifactobjects(i) = rectangle(app.DataAxes,'Position',[startpos 0 endpos-startpos max(app.data)],"FaceColor",[1 .8 .8 0.5]);
                uistack(app.artifactobjects(i),'bottom')
            end
        end

        function PlotNewArtifact(app)
            delete(app.newartifact);
            app.newartifact = rectangle(app.DataAxes,'Position',[app.starttime 0 app.endtime-app.starttime max(app.data)],"FaceColor",[.4 .8 .5 0.5]);            
            uistack(app.dataplot,'top')
        end        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, artifacts, data, time)
            app.mainapp = mainapp;
            app.artifacts = artifacts;
            app.data = data;
            app.time = time;
            app.starttime = min(time);
            app.endtime = max(time);

            app.ArtifactStartValue.Value = app.starttime;
            app.ArtifactEndValue.Value = app.endtime;
                        
            app.PlotOriginalData()
            app.PlotOriginalArtifacts()
            app.PlotNewArtifact()

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelAddArtifact();
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.ReturnNewArtifact();
        end

        % Value changed function: ArtifactEndValue
        function ArtifactEndValueValueChanged(app, event)
            value = app.ArtifactEndValue.Value;
            app.UpdateEndTime();       
        end

        % Value changed function: ArtifactStartValue
        function ArtifactStartValueValueChanged(app, event)
            value = app.ArtifactStartValue.Value;
            app.UpdateStartTime();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataAxes = uiaxes(app.UIFigure);
            title(app.DataAxes, 'Original Data')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.ArtifactStartValue = uieditfield(app.UIFigure, 'numeric');
            app.ArtifactStartValue.ValueChangedFcn = createCallbackFcn(app, @ArtifactStartValueValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Text = 'End';
            app.ArtifactEndValue = uieditfield(app.UIFigure, 'numeric');
            app.ArtifactEndValue.ValueChangedFcn = createCallbackFcn(app, @ArtifactEndValueValueChanged, true);
            app.AcceptButton = uibutton(app.UIFigure, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArtifactCorrectionAppAddWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end