classdef ArtifactCorrectionAppV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        InfoPanel                  matlab.ui.container.Panel
        ArtifactLabel              matlab.ui.control.Label
        ArtifactsLabel             matlab.ui.control.Label
        OptionsPanel               matlab.ui.container.Panel
        ArtifactEndValue           matlab.ui.control.NumericEditField
        EndEditFieldLabel          matlab.ui.control.Label
        ArtifactStartValue         matlab.ui.control.NumericEditField
        StartEditFieldLabel        matlab.ui.control.Label
        Select2artifactstomergeontheouterstartendLabel_2  matlab.ui.control.Label
        Select1artifactandpresstoremovetheentryLabel  matlab.ui.control.Label
        PressthenclickonthestartendpositionintheOriginalDataLabel  matlab.ui.control.Label
        Select2artifactstomergeontheouterstartendLabel  matlab.ui.control.Label
        DeleteButton               matlab.ui.control.Button
        AddButton                  matlab.ui.control.Button
        MergeButton                matlab.ui.control.Button
        ConclusionPanel            matlab.ui.container.Panel
        AcceptButton               matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        SolutionOptionsPanel       matlab.ui.container.Panel
        SolutionsCustomValue       matlab.ui.control.NumericEditField
        customvalueEditFieldLabel  matlab.ui.control.Label
        SolutionsListBox           matlab.ui.control.ListBox
        RightPanel                 matlab.ui.container.Panel
        SolutionAxes               matlab.ui.control.UIAxes
        ValidationAxes             matlab.ui.control.UIAxes
        OriginalAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        time % options X axis
        original % Description
        validation
        solution
        selectedartifacts
        artifacts
    end

    methods (Access = public)

        
        function CalculateSolution(app)
            app.solution = app.original;
            for i=1:length(app.artifacts)
                startpos = find(app.time==app.artifacts(i).start);
                endpos = find(app.time==app.artifacts(i).end);
                newarray = GetArtifactOutcome(app,app.artifacts(i));
                app.solution(startpos:endpos) = newarray;
            end
        end
        

        % Reset the current visuals, and draw the latest visual state
        function PlotVisuals(app)
            cla(app.OriginalAxes)
            cla(app.ValidationAxes)
            cla(app.SolutionAxes)
            app.PlotArtifacts();
            hold(app.OriginalAxes,'on')
            app.PlotAxisData();
            hold(app.OriginalAxes,'off')
        end
        %Plot the latest data on the original / validation / solution axes
        function PlotAxisData(app)
            plot(app.OriginalAxes,app.time,app.original)
            plot(app.ValidationAxes,app.time,app.validation)
            plot(app.SolutionAxes,app.time,app.solution)
        end
        %Plot all artifacts on the original axes
        function PlotArtifacts(app)
            for i=1:length(app.artifacts)
                startpos = app.artifacts(i).start;
                endpos = app.artifacts(i).end;
                rectangle(app.OriginalAxes,'Position',[startpos 0 endpos-startpos max(app.original)],"FaceColor",'#ffcccb')
            end
        end

        function out = GetArtifactOutcome(app,artifact)            
            startpos = find(app.time==artifact.start);
            endpos = find(app.time==artifact.end);
            
            artifactarray = app.original(startpos:endpos);
            
            if strcmp(artifact.solution,"linear")
                artifactarray = linspace(artifactarray(1),artifactarray(length(artifactarray)),length(artifactarray));
            end
            if strcmp(artifact.solution,"NaN")
                artifactarray = NaN(length(artifactarray),1);
            end
            if strcmp(artifact.solution,"startpos")
                artifactarray = linspace(artifactarray(1),artifactarray(1),length(artifactarray));
            end
            if strcmp(artifact.solution,"endpos")
                artifactarray = linspace(artifactarray(length(artifactarray)),artifactarray(length(artifactarray)),length(artifactarray));
            end
            if strcmp(artifact.solution,"custom")
                artifactarray = linspace(app.SolutionsCustomValue.Value,app.SolutionsCustomValue.Value,length(artifactarray));
            end
            if strcmp(artifact.solution,"spline")

                a = artifactarray(1);
                
                a=[a,NaN(1,(length(artifactarray))-1,'single')];

                a = [a,artifactarray(length(artifactarray))];
                
                x = 1:length(a) ;
                
                a(isnan(a)) = interp1(x(~isnan(a)),a(~isnan(a)),x(isnan(a)), 'spline');
                
                artifactarray = a;
            end   
            out = artifactarray;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, original, artifacts, time, validation)
            %make app fullscreen
            drawnow;
            app.UIFigure.WindowState = 'maximized';

            %Set necessary properties / variable
            app.original = original;
            app.artifacts = artifacts;
            if (isempty(time))
                app.time = linspace(0,length(app.original)-1,length(app.original));
            else
                app.time = time;
            end
            if (isempty(validation))
                app.validation = linspace(0,length(app.original)-1,length(app.original));
            else
                app.validation = validation;
            end

            %Perform initial calculations
            app.CalculateSolution();

            %Set initial values
            app.ArtifactLabel.Text = mat2str(length(app.artifacts));

            %Set initial axes
            app.PlotVisuals();

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SolutionOptionsPanel = uipanel(app.LeftPanel);
            app.SolutionOptionsPanel.Title = 'Solution Options';
            app.SolutionsListBox = uilistbox(app.SolutionOptionsPanel);
            app.SolutionsListBox.Items = {'linear', 'spline', 'NaN', 'startpos', 'endpos', 'custom'};
            app.SolutionsListBox.Value = 'linear';
            app.customvalueEditFieldLabel = uilabel(app.SolutionOptionsPanel);
            app.customvalueEditFieldLabel.Text = 'custom value';
            app.SolutionsCustomValue = uieditfield(app.SolutionOptionsPanel, 'numeric');
            app.ConclusionPanel = uipanel(app.LeftPanel);
            app.ConclusionPanel.Title = 'Conclusion';
            app.CancelButton = uibutton(app.ConclusionPanel, 'push');
            app.CancelButton.Text = 'Cancel';
            app.AcceptButton = uibutton(app.ConclusionPanel, 'push');
            app.AcceptButton.Text = 'Accept';
            app.OptionsPanel = uipanel(app.LeftPanel);
            app.OptionsPanel.Title = 'Options';
            app.MergeButton = uibutton(app.OptionsPanel, 'push');
            app.MergeButton.Text = 'Merge';
            app.AddButton = uibutton(app.OptionsPanel, 'push');
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.OptionsPanel, 'push');
            app.DeleteButton.Text = 'Delete';
            app.Select2artifactstomergeontheouterstartendLabel = uilabel(app.OptionsPanel);
            app.Select2artifactstomergeontheouterstartendLabel.Text = 'Select 2 artifacts to merge on the outer start & end';
            app.PressthenclickonthestartendpositionintheOriginalDataLabel = uilabel(app.OptionsPanel);
            app.PressthenclickonthestartendpositionintheOriginalDataLabel.Text = 'Press, then click on the start & end position in the Original Data';
            app.Select1artifactandpresstoremovetheentryLabel = uilabel(app.OptionsPanel);
            app.Select1artifactandpresstoremovetheentryLabel.Text = 'Select 1 artifact and press to remove the entry';
            app.Select2artifactstomergeontheouterstartendLabel_2 = uilabel(app.OptionsPanel);
            app.Select2artifactstomergeontheouterstartendLabel_2.Text = 'Select 1 artifact, and change the start & end value to change its duration';
            app.StartEditFieldLabel = uilabel(app.OptionsPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.ArtifactStartValue = uieditfield(app.OptionsPanel, 'numeric');
            app.EndEditFieldLabel = uilabel(app.OptionsPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.ArtifactEndValue = uieditfield(app.OptionsPanel, 'numeric');
            app.InfoPanel = uipanel(app.LeftPanel);
            app.InfoPanel.Title = 'Info';
            app.ArtifactsLabel = uilabel(app.InfoPanel);
            app.ArtifactsLabel.Text = 'Artifacts:';
            app.ArtifactLabel = uilabel(app.InfoPanel);
            app.ArtifactLabel.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.OriginalAxes = uiaxes(app.RightPanel);
            title(app.OriginalAxes, 'Original Data')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ValidationAxes = uiaxes(app.RightPanel);
            title(app.ValidationAxes, 'Validation Data')
            xlabel(app.ValidationAxes, 'X')
            ylabel(app.ValidationAxes, 'Y')
            zlabel(app.ValidationAxes, 'Z')
            app.SolutionAxes = uiaxes(app.RightPanel);
            title(app.SolutionAxes, 'Solution')
            xlabel(app.SolutionAxes, 'X')
            ylabel(app.SolutionAxes, 'Y')
            zlabel(app.SolutionAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArtifactCorrectionAppV2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end