classdef ArtifactReplacementWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CorrectButton              matlab.ui.control.Button
        UIAxes_Original            matlab.ui.control.UIAxes
        UIAxes_Artifact            matlab.ui.control.UIAxes
        SkipButton                 matlab.ui.control.Button
        UIAxes_Solution            matlab.ui.control.UIAxes
        ArtifactDataPanel          matlab.ui.container.Panel
        ArtifactIDLabel            matlab.ui.control.Label
        TotalArtifactsLabel        matlab.ui.control.Label
        IDLabel                    matlab.ui.control.Label
        TotalLabel                 matlab.ui.control.Label
        SolutionOptionsPanel       matlab.ui.container.Panel
        SolutionsListBoxLabel      matlab.ui.control.Label
        SolutionsListBox           matlab.ui.control.ListBox
        CustomValueEditFieldLabel  matlab.ui.control.Label
        CustomValueEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        time
        original
        correcteddata
        startpos
        artifact
        artifactcorrection
        correctiondata
        id
        total
        f
        defaultmethod
        customvalue
        endpos
        artifactarray
        resumetofunction
        correctdata
    end
    
    
    methods (Access = public)
        
        function Resume(app)
            app.resumetofunction = true;
        end
        
        function Close(app)
            delete(app);
        end
        
        function SetArtifactArray(app, value)
            clearvars app.artifactarray;            
            if value == "linear"
                linspace(app.original(app.startpos),app.original(app.endpos),(app.endpos-app.startpos)+1)
                app.artifactarray = linspace(app.original(app.startpos),app.original(app.endpos),(app.endpos-app.startpos)+1);
            end
            if value == "NaN"
                app.artifactarray = NaN(1,(app.endpos-app.startpos)+1);
            end
            if value == "startpos"
                app.artifactarray(1:1,1:(app.endpos-app.startpos)+1) = app.original(app.startpos);
            end
            if value == "endpos"
                app.artifactarray(1:1,1:(app.endpos-app.startpos)+1) = app.original(app.endpos);
            end
            if value == "custom"
                app.artifactarray(1:1,1:(app.endpos-app.startpos)+1) = app.CustomValueEditField.Value;
            end
            if value == "spline"
                if app.startpos>1
                    a = app.original(app.startpos-1:app.startpos);
                else
                    a = app.original(app.startpos);
                end
                
                a=[a,NaN(1,(app.endpos-app.startpos)-1,'single')];

                if app.endpos<length(app.original)
                    a = [a,app.original(app.endpos:app.endpos+1)];
                else
                    a = [a,app.original(app.endpos)];
                end
                
                x = 1:length(a) ;
                
                a(isnan(a)) = interp1(x(~isnan(a)),a(~isnan(a)),x(isnan(a)), 'spline');
                
                if app.startpos>1
                    a = a(2:end);
                end
                if app.endpos<length(app.original)
                    a = a(1:end-1);
                end
                
                a
                
                app.artifactarray = a;
            end
                
        end
        
        function PlotDataAxis(app)
            plot(app.UIAxes_Original,app.time,app.correcteddata,app.time,app.artifact)
            plot(app.UIAxes_Artifact,app.time(app.startpos:app.endpos), app.artifact(app.startpos:app.endpos))
            plot(app.UIAxes_Solution,app.time,app.correctiondata,app.time,app.artifactcorrection)
        end
        
        function CreateArtifactVisual(app)
            app.artifact = NaN(1,length(app.original));
            app.artifact(app.startpos:app.endpos) = app.original(app.startpos:app.endpos);
        end
        
        function CreateArtifactCorrectionVisual(app)
            app.artifactcorrection = NaN(1,length(app.original));
            app.artifactcorrection(app.startpos:app.endpos) = app.artifactarray;
            app.correctiondata = app.correcteddata;
            app.correctiondata(app.startpos:app.endpos) = NaN(1,length(app.artifactarray));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, time, original, correcteddata, startpos, endpos, id, total, defaultmethod, customvalue)
            %set required values from workspace (handy while designing window)
            %app.time=evalin('base','data.time');
            %app.original=evalin('base','data.original');
            %app.correcteddata=evalin('base','correcteddata');
            %app.startpos=evalin('base','startpos');
            %app.endpos=evalin('base','endpos');
            %app.id=evalin('base','i');
            %app.total=evalin('base','totalartifacts');
            
            %make app fullscreen
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            %set required values from input arguments
            app.time=time;
            app.original=original;
            app.correcteddata=correcteddata;
            app.startpos=startpos;
            app.endpos=endpos;
            app.id=id;
            app.total=total;
            app.defaultmethod=defaultmethod;
            app.customvalue=customvalue;
            
            %set artifact details
            app.IDLabel.Text = app.id;
            app.TotalLabel.Text = app.total;
            app.CustomValueEditField.Value = app.customvalue;
            
            %create and plot artifact array on axis
            CreateArtifactVisual(app);
            app.SolutionsListBox.Value = app.defaultmethod;
            app.SolutionsListBoxValueChanged(app);
            
            app.resumetofunction = false;
            app.correctdata = false;
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            app.correctdata = true;
            Resume(app);
        end

        % Value changed function: SolutionsListBox
        function SolutionsListBoxValueChanged(app, event)
            value = app.SolutionsListBox.Value;
            SetArtifactArray(app,value);
            CreateArtifactCorrectionVisual(app);
            PlotDataAxis(app);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            Resume(app);
        end

        % Value changed function: CustomValueEditField
        function CustomValueEditFieldValueChanged(app, event)
            if (app.SolutionsListBox.Value == "custom")
                SetArtifactArray(app,app.SolutionsListBox.Value);
                CreateArtifactCorrectionVisual(app);
                PlotDataAxis(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CorrectButton = uibutton(app.UIFigure, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct this Artifact';
            app.UIAxes_Original = uiaxes(app.UIFigure);
            title(app.UIAxes_Original, 'Original Data')
            xlabel(app.UIAxes_Original, 'Time')
            ylabel(app.UIAxes_Original, 'Original')
            app.UIAxes_Artifact = uiaxes(app.UIFigure);
            title(app.UIAxes_Artifact, 'Artifact')
            xlabel(app.UIAxes_Artifact, 'Time')
            ylabel(app.UIAxes_Artifact, 'Original')
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip this Artifact';
            app.UIAxes_Solution = uiaxes(app.UIFigure);
            title(app.UIAxes_Solution, 'Solution')
            xlabel(app.UIAxes_Solution, 'Time')
            ylabel(app.UIAxes_Solution, 'Original')
            app.ArtifactDataPanel = uipanel(app.UIFigure);
            app.ArtifactDataPanel.Title = 'Artifact Data';
            app.ArtifactIDLabel = uilabel(app.ArtifactDataPanel);
            app.ArtifactIDLabel.Text = 'Artifact ID:';
            app.TotalArtifactsLabel = uilabel(app.ArtifactDataPanel);
            app.TotalArtifactsLabel.Text = 'Total Artifacts:';
            app.IDLabel = uilabel(app.ArtifactDataPanel);
            app.IDLabel.Text = '0';
            app.TotalLabel = uilabel(app.ArtifactDataPanel);
            app.TotalLabel.Text = '0';
            app.SolutionOptionsPanel = uipanel(app.UIFigure);
            app.SolutionOptionsPanel.Title = 'Solution Options';
            app.SolutionsListBoxLabel = uilabel(app.SolutionOptionsPanel);
            app.SolutionsListBoxLabel.Text = 'Solutions';
            app.SolutionsListBox = uilistbox(app.SolutionOptionsPanel);
            app.SolutionsListBox.Items = {'linear', 'spline', 'NaN', 'startpos', 'endpos', 'custom'};
            app.SolutionsListBox.ValueChangedFcn = createCallbackFcn(app, @SolutionsListBoxValueChanged, true);
            app.SolutionsListBox.Value = 'linear';
            app.CustomValueEditFieldLabel = uilabel(app.SolutionOptionsPanel);
            app.CustomValueEditFieldLabel.Text = 'Custom Value';
            app.CustomValueEditField = uieditfield(app.SolutionOptionsPanel, 'numeric');
            app.CustomValueEditField.ValueChangedFcn = createCallbackFcn(app, @CustomValueEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArtifactReplacementWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end