classdef BeltApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MenuProject                     matlab.ui.container.Menu
        MenuProjectNew                  matlab.ui.container.Menu
        MenuProjectOpen                 matlab.ui.container.Menu
        MenuProjectRecent               matlab.ui.container.Menu
        MenuProjectClose                matlab.ui.container.Menu
        MenuSettings                    matlab.ui.container.Menu
        GraphMenu                       matlab.ui.container.Menu
        LatencyMenu                     matlab.ui.container.Menu
        LatencyAllMenu                  matlab.ui.container.Menu
        LatencyTruncateMenu             matlab.ui.container.Menu
        LatencySpecificRangeMenu        matlab.ui.container.Menu
        NormalizeMenu                   matlab.ui.container.Menu
        NormalizeNoMenu                 matlab.ui.container.Menu
        NormalizeYesMenu                matlab.ui.container.Menu
        LegendMenu                      matlab.ui.container.Menu
        LegendTopRightMenu              matlab.ui.container.Menu
        LegendBottomRightMenu           matlab.ui.container.Menu
        LegendTopLeftMenu               matlab.ui.container.Menu
        LegendBottomLeftMenu            matlab.ui.container.Menu
        LegendNoneMenu                  matlab.ui.container.Menu
        SmoothingMenu                   matlab.ui.container.Menu
        SmoothingNoneMenu               matlab.ui.container.Menu
        Smoothing20Menu                 matlab.ui.container.Menu
        Smoothing40Menu                 matlab.ui.container.Menu
        GrandAverageMenu                matlab.ui.container.Menu
        ShowGrandAverageofselectedconditionsMenu  matlab.ui.container.Menu
        ShowindividualaveragesofselectedconditionsMenu  matlab.ui.container.Menu
        MenuHelp                        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayoutTopLeft               matlab.ui.container.GridLayout
        projectnameLabel                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        MainPanel                       matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        MainTab                         matlab.ui.container.TabGroup
        MainTabHome                     matlab.ui.container.Tab
        BusyMsg                         matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        HomeHTML                        matlab.ui.control.HTML
        GridLayout8                     matlab.ui.container.GridLayout
        recentProject5Btn               matlab.ui.control.Button
        recentProject4Btn               matlab.ui.control.Button
        recentProject3Btn               matlab.ui.control.Button
        recentProject2Btn               matlab.ui.control.Button
        recentProject1Btn               matlab.ui.control.Button
        GridLayout6                     matlab.ui.container.GridLayout
        openProjectBtn                  matlab.ui.control.Button
        newProjectBtn                   matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        RecentprojectsLabel             matlab.ui.control.Label
        MainTabProject                  matlab.ui.container.Tab
        ProjectHTML                     matlab.ui.control.HTML
        MainTabSegmentation             matlab.ui.container.Tab
        SegmentationHTML                matlab.ui.control.HTML
        MainTabArtifactCorrection       matlab.ui.container.Tab
        BeltArtifactCorrectionComponent  BeltArtifactCorrectionComponent
        MainTabDeconvolution            matlab.ui.container.Tab
        DeconvolutionHTML               matlab.ui.control.HTML
        MainTabParticipantGraph         matlab.ui.container.Tab
        CleanscCheckBox                 matlab.ui.control.CheckBox
        NormalizedCheckBox              matlab.ui.control.CheckBox
        PhasicdriverCheckBox            matlab.ui.control.CheckBox
        PhasicscCheckBox                matlab.ui.control.CheckBox
        TonicscCheckBox                 matlab.ui.control.CheckBox
        RawscCheckBox                   matlab.ui.control.CheckBox
        ParticipantSkinConductanceAxes  matlab.ui.control.UIAxes
        MainTabGraph                    matlab.ui.container.Tab
        SelectSegmentsTree              matlab.ui.container.CheckBoxTree
        Segment1Node                    matlab.ui.container.TreeNode
        ShadedareaDropDown              matlab.ui.control.DropDown
        ShadedareaDropDownLabel         matlab.ui.control.Label
        DatatypeDropDown                matlab.ui.control.DropDown
        DatatypeDropDownLabel           matlab.ui.control.Label
        SavegraphButton                 matlab.ui.control.Button
        UpdateGraphButton               matlab.ui.control.Button
        SkinConductanceAxes             matlab.ui.control.UIAxes
        MainTabExport                   matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        LogPanel                        matlab.ui.container.Panel
        LogTextArea                     matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        project; 
    end

    properties (Access = private)
        segment_raw; % the segmented raw data that is send to the artifact correction component
        current_cfg; % the cfg struct that is send to the artifact correction component
        selected_segment; % the segment_nr and pp_nr of the currently highlighted segment
        busyMsg_counter = 1; % keep track of how many dots to append to the message
        busyMsg_msg; % Description
        automatically_proceed_next = false; % Description
        participant_graph % hold the currently displayed pp_nr and segment_nr
        release % release.version and release.date
    end

    methods (Access = public)
        
        %% CALLBACKS
        function OpenProject(app, filename)

            % load the project data
            load(filename, "-mat", "project");

            % store the project in the app variable
            app.project = project; %#ok<ADPROPLC>

            % If the project is opened from a different location than it
            % was created, we need to adjust the directories.
            % We will use the location of the opened .belt file for that.
            app.project.project_directory = fileparts(filename);
            app.project.raw_data_directory = fullfile(app.project.project_directory, '0.RawData');
            app.project.processed_data_directory = fullfile(app.project.project_directory, '2.ProcessedData');
            app.project.output_directory = fullfile(app.project.project_directory, '3.Output');
            SaveProject(app);
            
            % Show the project name in the title bar
            app.UIFigure.Name = ['Breda Experience Lab Toolbox (', app.release.version , ')     -== ', app.project.project_name, ' project ==-'];

            app.projectnameLabel.Text =  app.project.project_name;

            % populate the project tabel with the participant data
            app.populate_participant_table();

            % add this project to the 'recent' list in the prefences file
            app.add_recent();

            % open the project details tab
            app.MainTab.SelectedTab = app.MainTabProject;

            % update the segmentation info
            app.UpdateSegmentationInfo();

            % update the list of segments on the Graph tab
            app.PopulateGraphSegments();
                       
        end

        % update the list of segments on the Graph tab
        function PopulateGraphSegments(app)
            % Start fresh, remove all segments but the first
            app.SelectSegmentsTree.CheckedNodes = '';
            a = app.SelectSegmentsTree.Children;
            a.delete;
            % populate the list of segments in the Graph tab according to
            % the project
            for segment_i = 1:app.project.nof_segments
                if segment_i == 0
                    app.Segment1Node.Text = app.project.segment(segment_i).name;
                else
                    uitreenode(app.SelectSegmentsTree,"Text",app.project.segment(segment_i).name);
                end

            end
            % check all segments
            app.SelectSegmentsTree.CheckedNodes = app.SelectSegmentsTree.Children;
        end


        % Update the project details on the project html page
        function UpdateProjectInfo(app)
            % iniitialize variables
            segment_name(1:6)    = "";
            nof_segmented(1:6)   = 0;
            nof_cleaned(1:6)     = 0;
            nof_deconvolved(1:6) = 0;
            % get the numbers
            for segment_i = 1:app.project.nof_segments
                segment_name(segment_i) = app.project.segment(segment_i).name;
                nof_segmented(segment_i) = sum(app.project.segment(segment_i).segmented(:));
                nof_cleaned(segment_i) = sum(app.project.segment(segment_i).artifact_corrected(:));
                nof_deconvolved(segment_i) = sum(app.project.segment(segment_i).deconvolved(:));
            end
            %send the data to the html page
            app.ProjectHTML.Data = struct(...
                "ProjectName", app.project.project_name,...
			    "ProjectDir", app.project.project_directory,...
			    "NofSegments", app.project.nof_segments...
            );
			    % "Condition1", segment_name(1),...
			    % "Condition2", segment_name(2),...
			    % "NofSegmented1", nof_segmented(1),...
			    % "NofSegmented2", nof_segmented(2),...
			    % "NofCleaned1", nof_cleaned(1),...
			    % "NofCleaned2", nof_cleaned(2),...
			    % "NofDeconvolved1", nof_deconvolved(1),...
			    % "NofDeconvolved2", nof_deconvolved(2)...
            for segment_i = 1:app.project.nof_segments    
                condition      = ['Condition'      num2str(segment_i)];
                nofsegmented   = ['NofSegmented'   num2str(segment_i)];
                nofcleaned     = ['NofCleaned'     num2str(segment_i)];
                nofdeconvolved = ['NofDeconvolved' num2str(segment_i)];
			    app.ProjectHTML.Data.(condition)      = segment_name(segment_i);
			    app.ProjectHTML.Data.(nofsegmented)   = nof_segmented(segment_i);
			    app.ProjectHTML.Data.(nofcleaned)     = nof_cleaned(segment_i);
			    app.ProjectHTML.Data.(nofdeconvolved) = nof_deconvolved(segment_i);
            end

        end

        function UpdateSegmentationInfo(app)
            % Let the user know that the segmentation is in progress
            nof_pps = length(app.project.pp_labels);
            nof_pp_segments = nof_pps * app.project.nof_segments;
            nof_pp_segments_done = 0;
            for segment_ii = 1:app.project.nof_segments % NOTE the use of the double i to avoid disrupting the parent for loop
                nof_pp_segments_done = nof_pp_segments_done + sum(app.project.segment(segment_ii).segmented(:));
            end
            progress_value = nof_pp_segments_done / nof_pp_segments * 100;

            if progress_value == 100
                % Segmentation is complete
                app.SegmentationHTML.Data = struct(...
                    "SegmentationSegment", ['Segmentation is complete'],...
                    "SegmentationProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
                    "SegmentationStatus", 'You can now proceed with cleaning the data.<br>Click a box in the Clean column in the table on the left to start the Artifact Correction procedure for that data segment.'...
                    );
            else
                % update the Segmentation html page
                app.SegmentationHTML.Data = struct(...
                    "SegmentationSegment", 'Some data still need segmenting',...
                    "SegmentationProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
                    "SegmentationStatus", 'Click a box in the Cut column on the left to start segmenting.'...
                    );
            end
            drawnow();% update the graphics

        end


        function OpenRecentProject(app, recent_index)
            % open the recent project according to the selected index 
            pref = ['recent', num2str(recent_index)];

            if ispref('belt', pref)
                % get the project name, dir, and date from the preferences
                recent = getpref('belt', pref);

                % read the project data
                % recent(2) is the project directory
                % recent(1) is the project name
                full_file = fullfile(recent{2}, append('project_', recent{1}, '.belt'));

                % load the project and show the project data
                app.OpenProject(full_file);

                % write a message in the log
                msg = ['recent project ''', app.project.project_name, ''' opened'];
                app.AppendLog(msg);

            end
        end

        % the index of the recent menu item is stored as event.Source.Position
       function OpenRecentProjectFromMenu(app, event)
            app.OpenRecentProject(event.Source.Position);
        end

        %  % the index of the recent menu item is stored as event.HTMLEventData
        % function OpenRecentProjectFromHTML(app, event)
        %     app.OpenRecentProject(event.HTMLEventData);
        % end

        % save the project struct
        function SaveProject(app)
            % save the project struct as a matlab datafile
            path_filename = fullfile(app.project.project_directory, ['project_' app.project.project_name '.belt']);
            project = app.project;
            save(path_filename, 'project');

        end

        % do artifact correction
        function ArtifactCorrection(app, cfg)

            % First check whether data was provided
            if isfield(cfg, 'pp_nr') && cfg.pp_nr == 0
                % no data provided, show an empty artifact correction window
                app.BeltArtifactCorrectionComponent.ClearData();
            end

            % check input
            if ~isfield(app.project, 'project_name')
                uialert(app.UIFigure,'The project struct has no project_name field. I cannot create a project bookkeeping file without it.', 'Error');
            end
            if ~isfield(app.project, 'project_directory')
                uialert(app.UIFigure,'The project struct has no project_directory field. I cannot create a project bookkeeping file without it.', 'Error');
            end
            if ~isfield(app.project, 'raw_data_directory')
                uialert(app.UIFigure,'The project struct has no raw_data_directory field. Run create_new_project() first.', 'Error');
            end
            if ~isfield(app.project, 'processed_data_directory')
                uialert(app.UIFigure,'The project struct has no processed_data_directory field. Run create_new_project() first.', 'Error');
            end
            if ~isfield(cfg, 'segment_nr')
                uialert(app.UIFigure,'Provide a cfg.segment_nr', 'Error');
            end
            if ~isfield(cfg, 'pp_nr')
                uialert(app.UIFigure,'Provide a cfg.pp_nr', 'Error');
            end
            if ~isfield(cfg, 'handle_already_cleaned_segments')
                cfg.handle_already_cleaned_segments = 'skip';
            end

            % set the parameters for the artifact detection algorithm
            if ~isfield(cfg, 'timwin')
                cfg.timwin = 20;
            end
            if ~isfield(cfg, 'threshold')
                cfg.threshold = 5;
            end
            if ~isfield(cfg, 'default_solution')
                cfg.default_solution = 'linear';
            end

            % for readability of the code
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            if ~isfield(cfg, 'segment_identifier')
                cfg.segment_identifier = ['Segment: ', segment_name, ', participant: ', pp_label];
            end

            % check whether artifact correction is even possible and needed
            [answer, msg] = artifact_correction_is_possible(cfg, app.project);
            app.AppendLog(msg);
            if ~answer % artifact correction is not possible 
                return; % skip the rest of the function
            else
                % check whether artifact correction has already been done
                if app.project.segment(segment_nr).artifact_corrected(pp_nr)
                    % segment has already been artifact corrected,
                    % ask whether it should be done again
                    dlgtitle = 'Redo artifact correction?';
                    question = sprintf('Artifact correction has already been done for this participant.\nWould you like to redo it?');
                    opts.Default = 'No';
                    question_answer = questdlg(question, dlgtitle, 'Yes','No', opts.Default);
                    % Handle response
                    switch question_answer
                        case 'No'
                            % User decided not to redo artifact corrections, 
                            % so skip the remainder of this function
                            return;
                        case 'Yes'
                            % process the data again
                            % proceed with the remainder of the function
                            % In such case we don't want to automatically
                            % proceed with the next segment (I guess), so keep track
                            app.automatically_proceed_next = false;

                    end
                else
                    app.automatically_proceed_next = true;
                end
            end

            % Show the correct segment in the participant data table
            app.TabGroup.SelectedTab = app.TabGroup.Children(segment_nr);

            % open the artifact correction tab
            app.MainTab.SelectedTab = app.MainTabArtifactCorrection;
            
            % Let the user know that the file is being loaded
           % msg = sprintf("Loading data of participant %s (condition %s) ", pp_label, segment_name);
            msg = sprintf("Loading data");
            thandle = app.showBusyMessage(msg);

            % load the segmented raw data
            path_filename = fullfile(app.project.processed_data_directory, ['segment_raw_' segment_name  '_' pp_label '.mat']);
            load(path_filename, 'segment_raw'); % loads segment_raw

            % store some data for later processing
            app.segment_raw = segment_raw;
            app.current_cfg = cfg;

            % highlight the segment in the participant table
            app.highlight_segment(cfg);

            % get potential artifacts
            cfg.artifacts = get_potential_artifacts(cfg, segment_raw);
            
            % open the artifact correction window to get the artifact corrected data
            % corrected_data = open_artifact_correction_window(cfg, segment_raw); % opens the app in a seperate window 
            
            % wait until the busy message is done
            wait(thandle);

            % show the raw data and potential artifacts in the the artifact correction component
            cfg.time = segment_raw.time;
            app.BeltArtifactCorrectionComponent.showData(cfg, segment_raw.conductance_raw);

        end

        % find the next segment/pp for artifact correction
        function next_cfg = FindNextSegmentToClean(app, current_cfg) 
            if ~app.automatically_proceed_next % do not start the next segment
                current_cfg.pp_nr = 0;
                current_cfg.segment_nr = 0;
            else % do find the next segment
                pp_nr = current_cfg.pp_nr;           % the current participant number 
                segment_nr = current_cfg.segment_nr; % the current segment number
                nof_pps = length(app.project.pp_labels); % the number of participants
                nof_segments = app.project.nof_segments; % the number of segments in the project
    
                still_searching = true; % indicate that we are searching for the next segment
    
                while still_searching
                    % move to the next pp_nr within the current segment
                    if pp_nr < nof_pps % if it was not the last participant
                        pp_nr = pp_nr + 1;
                    else % it was the last participant of this segment
                        % see if we can move to the next segment
                        if segment_nr < nof_segments % if it was not the last segment
                            segment_nr = segment_nr + 1;
                            pp_nr = 1;
                        else % we reached the last pp of the last segment
                            % TODO :: ask to continue the search at pp 1, segment 1
                            current_cfg.pp_nr = 0;
                            current_cfg.segment_nr = 0;
                            current_cfg = rmfield(current_cfg, 'segment_identifier');
                            next_cfg = current_cfg; 
                            % skip the rest of the function, return the new pp_nr and segment_nr
                            return;
                        end
                    end
                    % check whether artifact correction is possible for this segment 
                    current_cfg.pp_nr = pp_nr;
                    current_cfg.segment_nr = segment_nr;
                    [answer, msg] = artifact_correction_is_possible(current_cfg, app.project);
                    app.AppendLog(msg);
                    if answer % we found the next segment
                        % check whether artifact correction has already been done
                        if ~app.project.segment(segment_nr).artifact_corrected(pp_nr) % this segment has not yet been cleaned
                            still_searching = false;
                        end
                    end
                end
            end

            % return the new pp_nr and segment_nr
            current_cfg = rmfield(current_cfg, 'segment_identifier');
            next_cfg = current_cfg; 

        end


        % segment (cut) the raw conductance data according to start and end times of each condition
        % TODO: for now this just segments all pp, all conditions in one go. Is there a need for a more subtle approach?
        function Segmentation(app, cfg)

            % open the segmentation tab
            app.MainTab.SelectedTab = app.MainTabSegmentation;
            
            % with which participant should we start
            if isfield(cfg, 'pp_nr') && cfg.pp_nr > 0
                % pp data was provided, start with the selected pp
                start_pp_nr = cfg.pp_nr;
            else
                start_pp_nr = 1;
            end

            % with which segment should we start 
            if isfield(cfg, 'segment_nr') && cfg.segment_nr > 0
                % pp data was provided, start with the selected pp
                start_segment_nr = cfg.segment_nr;
            else
                start_segment_nr = 1;
            end

            nof_pps = length(app.project.pp_labels);
            nof_pp_segments = nof_pps * app.project.nof_segments;
            
            % segment all conditions
            for segment_i = start_segment_nr:app.project.nof_segments
                segment_name = app.project.segment(segment_i).name;
                % Show the correct segment in the participant data table
                app.TabGroup.SelectedTab = app.TabGroup.Children(segment_i);

                % start the next segment from the first pp
                if ~(segment_i == start_segment_nr)
                    start_pp_nr = 1;
                end

                % segment data of all pps
                for pp_i = start_pp_nr:nof_pps
                    pp_label = app.project.pp_labels{pp_i};
                    cfg = [];
                    cfg.segment_nr = segment_i;
                    cfg.pp_nr = pp_i;
                    % provide the sampling frequency for Shimmer data, this is ignored for Empatica data (default = 64)
                    cfg.fsample = 64; % TODO: let the user decided by adding a setting in the menu
                    cfg.handle_already_segmented_data = 'skip'; % 'skip|ask|redo'
                    [app.project, msg] = belt_get_data_segment(cfg, app.project);

                    % highlight the segment in the participant table
                    app.highlight_segment(cfg);

                    % Let the user know that the segmentation is in progress
                    nof_pp_segments_done = 0;
                    for segment_ii = 1:app.project.nof_segments % NOTE the use of the double i to avoid disrupting the parent for loop
                        nof_pp_segments_done = nof_pp_segments_done + sum(app.project.segment(segment_ii).segmented(:));
                    end
                    progress_value = nof_pp_segments_done / nof_pp_segments * 100;
                    % update the Segmentation html page
                    app.SegmentationHTML.Data = struct(...
                        "SegmentationSegment", ['Now segmenting the data of condition ', segment_name, ', participant: ',pp_label],...
			            "SegmentationProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			            "SegmentationStatus", 'Processing ...'...
                    );
                    drawnow();% update the graphics

                    % update the table
                    app.populate_participant_table();

                    % write a message in the log
                    app.AppendLog(msg);
                end
            end

            % Segmentation is complete
            app.SegmentationHTML.Data = struct(...
                "SegmentationSegment", ['Segmentation is complete'],...
                "SegmentationProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
                "SegmentationStatus", 'You can now proceed with cleaning the data.<br>Click a box in the Clean column in the table on the left to start the Artifact Correction procedure for that data segment.'...
                );


        end


        % deconvolve (split) the conductance data into its phasic and tonic part
        function Deconvolution(app, cfg)
            % TODO: add [START] and [STOP] buttons to the html page

            % open the deconvolution tab
            app.MainTab.SelectedTab = app.MainTabDeconvolution;

            % with which participant should we start deconvolution
            if isfield(cfg, 'pp_nr') && cfg.pp_nr > 0
                % pp data was provided, start with the selected pp
                start_pp_nr = cfg.pp_nr;
            else
                start_pp_nr = 1;
            end

            % with which segment should we start deconvolution
            if isfield(cfg, 'segment_nr') && cfg.segment_nr > 0
                % pp data was provided, start with the selected pp
                start_segment_nr = cfg.segment_nr;
            else
                start_segment_nr = 1;
            end

            % the number of participants
            nof_pps = length(app.project.pp_labels);

            % keep track of whether a tempfolder was created
            tempfoldercreated = false; 

            % initialize the progress value
            progress_value = 0;

            for segment_i = start_segment_nr:app.project.nof_segments


                % start the next segment from the first pp
                if ~(segment_i == start_segment_nr)
                    start_pp_nr = 1;
                end

                for pp_i = start_pp_nr:nof_pps

                    % construct config for the actual deconvolution function
                    cfg.segment_nr = segment_i;
                    cfg.pp_nr = pp_i;
                    cfg.conductance = 'conductance_artifact_corrected';
                    cfg.handle_already_deconvolved_segments = 'skip'; % TODO: make this a configurable setting
                    cfg.tempdir = char(fullfile(app.project.project_directory, '\Temp')); % temporary directory for datafiles % noodoplossing: maak een char van de tempdir, anders geeft Ledalab een error

                    % for readability of the code
                    pp_nr = cfg.pp_nr;
                    pp_label = app.project.pp_labels{pp_nr};
                    segment_nr = cfg.segment_nr;
                    segment_name = app.project.segment(segment_nr).name;

                    % check whether deconvolution is even possible and needed
                    [answer, msg] = deconvolution_is_possible(cfg, app.project);
                    app.AppendLog(msg);
                    if ~answer % deconvolution is not possible or not needed
                        continue; % skip the rest of the for loop, move to the next pp
                    end

                    % Show the correct segment in the participant data table
                    app.TabGroup.SelectedTab = app.TabGroup.Children(segment_i);

                    % create the temp folder, if needed
                    if ~exist(cfg.tempdir, "dir")
                        % the folder does not exist
                        [status, msg2, msgID] = mkdir(cfg.tempdir); % create it
                        tempfoldercreated = true; % take note that we created the folder. Remove it when we are done
                    end

                    % highlight the segment in the participant table
                    app.highlight_segment(cfg);

                    % Let the user know that the file is being loaded
                    nof_pp_segments = nof_pps * app.project.nof_segments;
                    nof_pp_segments_done = 0;
                    for segment_ii = 1:app.project.nof_segments % NOTE the use of the double i to avoid disrupting the parent for loop
                        nof_pp_segments_done = nof_pp_segments_done + sum(app.project.segment(segment_ii).deconvolved(:));
                    end
                    progress_value = nof_pp_segments_done / nof_pp_segments * 100;
                    app.DeconvolutionHTML.Data = struct(...
                        "DeconvolveSegment", ['Now deconvolving the data of condition ', segment_name, ', participant: ',pp_label],...
        			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
        			    "DeconvolveStatus", 'Loading data ...'...
                        );
                    drawnow();% update the graphics

                    % load the data
                    path_filename = fullfile(app.project.processed_data_directory, ['segment_artifact_corrected_' segment_name '_' pp_label '.mat']);
                    load(path_filename, 'segment_artifact_corrected');

                    % Let the user know that the data is being resampled
                    app.DeconvolutionHTML.Data = struct(...
                        "DeconvolveSegment", ['Now deconvolving the data of condition ', segment_name, ', participant: ',pp_label],...
        			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
        			    "DeconvolveStatus", 'Resampling the data to 16Hz...'...
                        );
                    drawnow();% update the graphics

                    % set resampling TODO: make this a setting in preferences
                    cfg_resample = [];
                    cfg.resample = 'yes';
                    % cfg.resample = 'no';
                    cfg.fsample = 16;

                    if isfield(cfg, 'resample') && strcmp(cfg.resample, 'yes') && isfield(cfg, 'fsample')
                        % resample the data before deconvolution
                        cfg_resample.fsample = cfg.fsample;
                        cfg_resample.valueList = ["conductance_artifact_corrected"];
                        segment_artifact_corrected = resample_generic(cfg_resample,  segment_artifact_corrected);
                    end

                    % add the zscored conductance
                    segment_artifact_corrected.conductance_artifact_corrected_z = normalize(segment_artifact_corrected.conductance_artifact_corrected);
                    cfg.conductance_z   = 'conductance_artifact_corrected_z';

                    % Let the user know that deconvolution has started
                    app.DeconvolutionHTML.Data = struct(...
                        "DeconvolveSegment", ['Now deconvolving the data of condition ', segment_name, ', participant: ',pp_label],...
        			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
        			    "DeconvolveStatus", 'Deconvolution in progress (this may take a while) ...'...
                        );
                    drawnow();% update the graphics

                    % do the deconvolution thing
                    segment_deconvolved = deconvolve_eda(cfg, segment_artifact_corrected);

                    % Let the user know that deconvolution is complete
                    app.DeconvolutionHTML.Data = struct(...
                        "DeconvolveSegment", ['Now deconvolving the data of condition ', segment_name, ', participant: ',pp_label],...
        			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
        			    "DeconvolveStatus", 'Deconvolution complete ...'...
                        );
                    drawnow();% update the graphics

                    % do some reorganizing and renaming
                    [segment_deconvolved.conductance_phasicDriver]   = segment_deconvolved.phasicDriver;
                    [segment_deconvolved.conductance_phasicDriver_z] = segment_deconvolved.phasicDriver_z;
                    [segment_deconvolved.conductance_phasic]   = segment_deconvolved.phasic;
                    [segment_deconvolved.conductance_phasic_z] = segment_deconvolved.phasic_z;
                    [segment_deconvolved.conductance_tonic]    = segment_deconvolved.tonic;
                    [segment_deconvolved.conductance_tonic_z]  = segment_deconvolved.tonic_z;
                    segment_deconvolved = rmfield(segment_deconvolved,'phasicDriver');
                    segment_deconvolved = rmfield(segment_deconvolved,'phasicDriver_z');
                    segment_deconvolved = rmfield(segment_deconvolved,'phasic');
                    segment_deconvolved = rmfield(segment_deconvolved,'phasic_z');
                    segment_deconvolved = rmfield(segment_deconvolved,'tonic');
                    segment_deconvolved = rmfield(segment_deconvolved,'tonic_z');
                    segment_deconvolved = rmfield(segment_deconvolved,'conductance_artifact_corrected');
                    segment_deconvolved = rmfield(segment_deconvolved,'conductance_artifact_corrected_z');
                    segment_deconvolved = orderfields(segment_deconvolved,...
                        {'pp_label', ...
                        'segment_name', ...
                        'datatype',...
                        'orig', ...
                        'initial_time_stamp', ...
                        'initial_time_stamp_mat', ...
                        'fsample', ...
                        'timeoff', ...
                        'event', ...
                        'analysis', ...
                        'time', ...
                        'eventchan', ...
                        'conductance_phasicDriver', ...
                        'conductance_phasicDriver_z', ...
                        'conductance_phasic', ...
                        'conductance_phasic_z', ...
                        'conductance_tonic', ...
                        'conductance_tonic_z' ...
                        });

                    % Let the user know that saving has started
                    app.DeconvolutionHTML.Data = struct(...
                        "DeconvolveSegment", ['Now deconvolving the data of condition ', segment_name, ', participant: ',pp_label],...
        			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
        			    "DeconvolveStatus", 'Deconvolution complete. Saving the data ...'...
                        );
                    drawnow();% update the graphics

                    % save the deconvolved data
                    msg = save_deconvolved_data(cfg, app.project, segment_deconvolved);

                    % update the bookkeeping of the project
                    app.project.segment(segment_nr).deconvolved(pp_nr) = true;

                    % save the project bookkeeping
                    save_project(app.project);

                    % update the table
                    app.populate_participant_table();

                    % Let the user know that saving is complete
                    nof_pp_segments_done = 0;
                    for segment_ii = 1:app.project.nof_segments % NOTE the use of the double i to avoid disrupting the parent for loop
                        nof_pp_segments_done = nof_pp_segments_done + sum(app.project.segment(segment_ii).deconvolved(:));
                    end
                    progress_value = nof_pp_segments_done / nof_pp_segments * 100;
                    % Let the user know that saving is complete
                    app.DeconvolutionHTML.Data = struct(...
                        "DeconvolveSegment", ['Now deconvolving the data of condition ', segment_name, ', participant: ',pp_label],...
        			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
        			    "DeconvolveStatus", 'Saving complete! Starting the next segment ...'...
                        );
                    drawnow();% update the graphics


                    % Provide some feedback
                    app.AppendLog(msg);


                end
            end

            app.DeconvolutionHTML.Data = struct(...
                "DeconvolveSegment", 'Deconvolution is complete.',...
			    "DeconvolveProgress", ['<label><progress value="', num2str(progress_value), '" max="100" width="600px"></progress></label>'],...
			    "DeconvolveStatus", 'Have a look at the results in the Graph tab ...'...
                );
            drawnow();% update the graphics

            % if we created a temporary folder, we should clean up
            if tempfoldercreated
                try
                    rmdir(cfg.tempdir, 's'); % this fails sometimes, don't know why
                catch ME
                    app.AppendLog('Removing the Temp directory failed.')
                end
            end

        end


        % find the next segment/pp for artifact correction
        function next_cfg = FindNextSegmentToDeconvolve(app, current_cfg) 
            % pp_nr = current_cfg.pp_nr;           % the current participant number 
            % segment_nr = current_cfg.segment_nr; % the current segment number
            % nof_pps = length(app.project.pp_labels); % the number of participants
            % nof_segments = app.project.nof_segments; % the number of segments in the project
            % 
            % still_searching = true; % indicate that we are searching for the next segment
            % next_pp_nr = 0;
            % next_segment_nr = 0;
            % 
            % while still_searching
            %     % move to the next pp_nr within the current segment
            %     if pp_nr < nof_pps % if it was not the last participant
            %         pp_nr = pp_nr + 1;
            %     else % it was the last participant of this segment
            %         % see if we can move to the next segment
            %         if segment_nr < nof_segments % if it was not the last segment
            %             segment_nr = segment_nr + 1;
            %             pp_nr = 1;
            %         else % we reached the lat pp of the last segment
            %             % TODO :: ask to continue the search at pp 1, segment 1
            %             current_cfg.pp_nr = 0;
            %             current_cfg.segment_nr = 0;
            %             still_searching = false;
            %         end
            %     end
            %     % check whether artifact correction is possible for this segment 
            %     current_cfg.pp_nr = pp_nr;
            %     current_cfg.segment_nr = segment_nr;
            %     [answer, msg] = artifact_correction_is_possible(current_cfg, app.project);
            %     app.AppendLog(msg);
            %     if answer % we found the next segment
            %         still_searching = false;
            %     end
            % end
            % 
            % % return the new pp_nr and segment_nr
            % current_cfg = rmfield(current_cfg, 'segment_identifier');
            % next_cfg = current_cfg; 
            % 
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % toggle include variable (true|false)
        function ToggleInclude(app, cfg)
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;

            if app.project.segment(segment_nr).include(pp_nr) % if true
                include_status = 'false'; % set to false
                app.project.segment(segment_nr).include(pp_nr) = 0;
            else % if false
                include_status = 'true'; % set to true
                app.project.segment(segment_nr).include(pp_nr) = 1;
            end       
            % save the project
            app.SaveProject();
            % update the table
            app.populate_participant_table();
            % provide feedback
            msg = sprintf('Segment name  = ''%s'', participant ''%s'': include set to ''%s''.', segment_name, pp_label, include_status);
            app.AppendLog(msg);
        end

        %% VISUALS

        %% UI ELEMENTS


        %% CALCULATIONS


    end
    
    methods (Access = private)
        
        
        % Add event to the log
        function AppendLog(app, msg)
            if ~isempty(msg)
                t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
                t = string(t);
                new_text = sprintf('[%s] %s\n', t, msg);
                app.LogTextArea.Value = [app.LogTextArea.Value(:)', {new_text}];
                scroll(app.LogTextArea, "bottom");
            end
        end


        % populate the participant data table
        function populate_participant_table(app)
            % update the tabs
            app.update_tabs();

            % create a table from the project data
            for segment_i = 1:app.project.nof_segments
                pp = app.project.pp_labels;
                seg = logical(app.project.segment(segment_i).segmented);
                art = logical(app.project.segment(segment_i).artifact_corrected);
                dec = logical(app.project.segment(segment_i).deconvolved);
                inc = logical(app.project.segment(segment_i).include);
                t = table(pp, seg, art, dec, inc);

                % fill the table with the contents of table t
                app.UITable(segment_i).Data = t;        

                % show the changes
                app.UITable(segment_i).Visible = 'on';
                
                % refresh the gui to make these changes visible
                drawnow();

                % add the select table cell callback
                app.UITable(segment_i).CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);

            end

            % Update the project details on the project html page
            app.UpdateProjectInfo();

        end

        % update the tabs
        function update_tabs(app)
            % note the number of tabs
            nof_tabs = size(app.TabGroup.Children, 1);
            % for each segment, create a tab
            for segment_i = 1:app.project.nof_segments
                if segment_i > nof_tabs
                    % this tab does not exist yet, so create it.
                    tabgroup = app.TabGroup;
                    t = uitab(tabgroup, 'Title', app.project.segment(segment_i).name);
                    % copy the table of tab 1 to add it to this new tab.
                    % using matlab function copyobj(h, p); h = obj to copy, p = parent of the new object
                    app.UITable(segment_i) = copyobj(app.UITable(1), app.TabGroup.Children(segment_i));
                    % add callback function
                    app.UITable(segment_i).CellSelectionCallback = createCallbackFcn(app, @ParticipantsTableCellSelection, true);

                else
                    % this tab does exist, so rename it
                    app.TabGroup.Children(segment_i).Title = app.project.segment(segment_i).name;
                end
            end
            % remove tabs that we do not need
            if nof_tabs > app.project.nof_segments
                for tab_i = nof_tabs:-1:(app.project.nof_segments + 1)
                    delete(app.TabGroup.Children(tab_i));
                end
            end
        end

        % append the current project to the list of recent projects
        function add_recent(app)

            % first get the current list of recent projects
            temp_pref = cell(5, 3);
            if ispref('belt', 'recent1')
                temp_pref(1, :) = getpref('belt', 'recent1');
            end
            if ispref('belt', 'recent3')
                temp_pref(2, :) = getpref('belt', 'recent2');
            end
            if ispref('belt', 'recent3')
                temp_pref(3, :) = getpref('belt', 'recent3');
            end
            if ispref('belt', 'recent4')
                temp_pref(4, :) = getpref('belt', 'recent4');
            end
            if ispref('belt', 'recent5')
                temp_pref(5, :) = getpref('belt', 'recent5');
            end

            % add the current project as the most recent
            t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
            t = string(t);
            setpref('belt', 'recent1', {app.project.project_name, app.project.project_directory, t});
            new_pref = getpref('belt', 'recent1');


            % get the current recent list, and store in the next recent spots

            for recent_i = 1:5
                    % check whether the current project is not the same
                    if strcmp(new_pref(1), temp_pref(recent_i, 1)) && strcmp(new_pref(2), temp_pref(recent_i, 2))
                        % same project as current one, so skip this to avoid duplicates
                        % if we skipped one, the remaining recent projects can keep their place
                        break; % skip the rest of the for loop
                    else
                        % save the previous recent projects
                        recent_postion = "recent" + (recent_i + 1);
                        setpref('belt', recent_postion, temp_pref(recent_i, :));
                    end
            end

            % update the recent menu
           app.show_recent_projects();

            % remove preferences of belt app === for testing ===
            % rmpref('belt', 'recent1');
            % rmpref('belt', 'recent2');
            % rmpref('belt', 'recent3');
            % rmpref('belt', 'recent4');
            % rmpref('belt', 'recent5');
            % rmpref('belt');
        end

        % show the recent projects in the menu and on the home tab
        function show_recent_projects(app)
            
            % First remove the previous recent projects list
            delete(app.MenuProjectRecent.Children);
            html_recent = {'recent1','recent2','recent3','recent4','recent5'};
            
            % then add them again, based on the recent projects list 
            % that is stored in the preferences
            for recent_i = 1:5
                % hide the recent buttons from the home screen
                recentButton = app.("recentProject" + recent_i + "Btn");
                recentButton.Visible = 'off';

                pref = ['recent', num2str(recent_i)];

                if ispref('belt', pref) % if a recent project exists

                    % get the project name, dir, and date
                    recent = getpref('belt', pref); 

                    if ~isempty(recent{1})
                        % add it to the recent menu
                        mitem = uimenu(app.MenuProjectRecent);
                        mitem.MenuSelectedFcn = createCallbackFcn(app, @OpenRecentProjectFromMenu, true);
                        mitem.Text =recent{1};
                        % add it to the recent buttons on the Home screen
                        recentButton.Text = recent{1};
                        recentButton.Visible = 'on'; % make the button visible
                    end

                    % add it to the home tab
                    % === TODO ===
                    html_recent(recent_i) = recent(1);
                end
            end

            app.HomeHTML.Data = html_recent;
        end

        function highlight_segment(app, cfg)
            % remove the previous highlight
            if isfield(app.selected_segment, 'segment_nr')
                removeStyle(app.UITable(app.selected_segment.segment_nr));
            end

            if cfg.pp_nr > 0
                % then highlight the new segment
                s1 = uistyle;
                s1.FontWeight = 'Bold';
                s1.BackgroundColor = [0.7, 0.7, 0.7];
                app.UITable(cfg.segment_nr).addStyle(s1, 'row', cfg.pp_nr);
    
                % remember which segment_nr and pp_nr is highlighted
                app.selected_segment.segment_nr = cfg.segment_nr;
                app.selected_segment.pp_nr = cfg.pp_nr;
            end
        end

        function t = showBusyMessage(app, msg)
            % create a timer
            t = timer;

            % set call back functions
            t.StartFcn = @app.BusyMsgTimerStart; % when timer starts
            t.TimerFcn = @app.BusyMsgTimerUpdate; % after each period
            t.StopFcn  = @app.BusyMsgTimerStop; % when timer stops
            
            % specify timing properties
            t.period = 0.2; % seconds
            t.StartDelay = 0.2;
            t.TasksToExecute = 4; % number of times the update function is called
            t.ExecutionMode = 'fixedSpacing';

            % store the message in a global variable
            app.busyMsg_msg = msg;

            % start the timer
            t.start;
 
        end

        function BusyMsgTimerStart(app, mTimer, ~)
            % move the message display to the current tab
            app.BusyMsg.Parent = app.MainTab.SelectedTab;
            % set the counter for displaying the ...
            app.busyMsg_counter = 0;
            % append dots to the message
            msg_with_dots = sprintf("%s", app.busyMsg_msg);
            % update the mesage display with the requested text
            app.BusyMsg.Text = msg_with_dots;
            % make the message display visible
            app.BusyMsg.Position = [247,220,171,17];
            app.BusyMsg.Visible = "on";
            drawnow();
        end

        function BusyMsgTimerUpdate(app, mTimer, ~)
            % increase the dot counter
            app.busyMsg_counter = app.busyMsg_counter + 1;
            % update the message, add a dot to the end;
            msg_with_dots = sprintf("%s%s", app.busyMsg_msg, repmat(' .', 1, app.busyMsg_counter));
            app.BusyMsg.Text = msg_with_dots;
            drawnow();
        end

        function BusyMsgTimerStop(app, mTimer, ~)
            % hide the message
            app.BusyMsg.Position = [50,302,171,17];
            app.BusyMsg.Visible = "off";
            % stop the timer
            mTimer.stop;
            drawnow();
        end

        % Button pushed function: DrawParticipantGraph
        function DrawParticipantGraph(app, cfg)
            
            % clear the canvas
            cla(app.ParticipantSkinConductanceAxes);
            legend_i = 0; % keep track of the legend index

            % for readability of the code
            pp_nr = cfg.pp_nr;
            pp_label = app.project.pp_labels{pp_nr};
            segment_nr = cfg.segment_nr;
            segment_name = app.project.segment(segment_nr).name;      

            % store pp_nr and segment_nr for update on checkbox changes
            app.participant_graph.pp_nr = pp_nr;
            app.participant_graph.segment_nr = segment_nr;            

            % update the subtitle of the graph
            app.ParticipantSkinConductanceAxes.Subtitle.String = ['(', segment_name, ', ', pp_label, ')'];
            
            % Colours
            raw_data_colour                 = [0.2000 0.2000 0.2000]; % grey
            artifact_corrected_data_colour  = [0.4660 0.6740 0.1880]; % green
            tonic_data_colour               = [0.4940 0.1840 0.5560]; % purple
            phasic_data_colour              = [0.3000 0.3000 1.0000]; % blue
            phasicDriver_data_colour        = [0.8500 0.0050 0.0000]; % red
             
            % TODO: get these from the settings menu or otherwise
            cfg.latency = 'all';
            cfg.legend = 'yes';
            cfg.legendposition = 'northwest';
            cfg.legendnumcolumns = 1;
            cfg.smoothing = 'no';

            % get the raw data
            if app.project.segment(segment_nr).segmented(pp_nr)
                % get the raw data            
                path_filename = fullfile(app.project.processed_data_directory, ['segment_raw_' segment_name '_' pp_label '.mat']);
                load(path_filename);
                % segment_raw struct is now loaded with fields time and conductance_raw
            else
                % nothing to see here
                return;
            end

            % get the artifact corrected data
            if app.project.segment(segment_nr).artifact_corrected(pp_nr)
                path_filename = fullfile(app.project.processed_data_directory, ['segment_artifact_corrected_' segment_name '_' pp_label '.mat']);
                load(path_filename);
                % segment_artifact_corrected struct is now loaded with field conductance_artifact_corrected

                % enable the checkboxes for these data
                app.CleanscCheckBox.Enable = 'on';
            else
                % disable the checkboxes for these data
                app.CleanscCheckBox.Enable = 'off';
            end

            % get the deconvolved data
            if app.project.segment(segment_nr).deconvolved(pp_nr)
                path_filename = fullfile(app.project.processed_data_directory, ['segment_deconvolved_' segment_name '_' pp_label '.mat']);
                load(path_filename);
                % segment_deconvolved struct is now loaded with field conductance_phasic and conductance_tonic

                % downsample the raw data  and artifact corrected data to match the deconvolved sampling rate 
                cfg_resample = [];
                cfg_resample.resample = 'yes';
                cfg_resample.fsample = 1 / (segment_deconvolved.time(2) - segment_deconvolved.time(1));
                cfg_resample.valueList = ["conductance_raw"];
                segment_raw = resample_generic(cfg_resample,  segment_raw);
                cfg_resample.valueList = ["conductance_artifact_corrected"];
                segment_artifact_corrected = resample_generic(cfg_resample,  segment_artifact_corrected);

                % enable the checkboxes for these data
                app.PhasicdriverCheckBox.Enable = 'on';
                app.PhasicscCheckBox.Enable   = 'on';
                app.TonicscCheckBox.Enable    = 'on';
                app.NormalizedCheckBox.Enable   = 'on';
            else
                % disable the checkboxes for these data
                app.PhasicdriverCheckBox.Enable = 'off';
                app.PhasicscCheckBox.Enable   = 'off';
                app.TonicscCheckBox.Enable    = 'off';
                app.NormalizedCheckBox.Enable   = 'off';
            end

            %  set x (time)
            x = segment_raw.time';

            % set the latency (time range)
            if strcmp(cfg.latency, 'all')
                % use the whole latency range
                startindex = 1;
                endindex = length(x);
            else
                % Check whether latency is valid
                if ~isnumeric(cfg.latency) % latency should be two numbers
                    uialert(app.UIFigure,'Provided latency range is not valid. Use [start end] in seconds. Type help plot_timeseries_data for further info.', 'Error');
                end
                if cfg.latency(1) < min(x) % the start latency cannot be before the start of the data
                    uialert(app.UIFigure,'Provided latency range is not valid. The start time is smaller then the start of the data. Type help plot_timeseries_data for further info.', 'Error');
                end
                if cfg.latency(2) > max(x) % the end latency cannot be after the end of the data
                    uialert(app.UIFigure,'Provided latency range is not valid. The end time exceed the time range of the data. Type help plot_timeseries_data for further info.', 'Error');
                end
                % latency values appear in order
                starttime = cfg.latency(1);
                endtime   = cfg.latency(2);
                % find the start and end indices of these times
                startindex = find(x >= starttime, 1);
                endindex   = find(x >= endtime, 1);
                % truncate the time
                x = x(startindex:endindex);
            end
            nof_timepoints = (endindex-startindex+1);

            % tell the plotter to keep what is already on canvas
            hold(app.ParticipantSkinConductanceAxes, 'on');

            % get the raw data of the specified latency range
            raw_data = segment_raw.conductance_raw(startindex:endindex)';

            % then draw the x en y axis
            x_axis_line = line(app.SkinConductanceAxes, x,0*raw_data); % x = 0 axis
            y_axis_line = line(app.SkinConductanceAxes, 0*x,raw_data); % y = 0 axis
            set(x_axis_line, 'color', 'k','linewidth',1.0);
            set(y_axis_line, 'color', 'k','linewidth',1.0);

            % the raw data
            if app.RawscCheckBox.Value

                % smooth if wanted
                if strcmp(cfg.smoothing, 'yes')
                    raw_data = smoothdata(raw_data);
                end

                % finally draw the raw data
                line_raw = line(app.ParticipantSkinConductanceAxes, x, raw_data);
                set(line_raw, 'color', raw_data_colour, 'linewidth', 1, 'linestyle', ':');

                % add a legend, if requested
                if strcmp(cfg.legend, 'yes')
                    legend_i = legend_i + 1;
                    legenddata(legend_i) = line_raw;
                    legendlabels{legend_i} = 'raw sc';
                end
            end
            % the artifact corrected data
            if app.project.segment(segment_nr).artifact_corrected(pp_nr) && app.CleanscCheckBox.Value
                % cut to requested latency range
                artifact_corrected_data = segment_artifact_corrected.conductance_artifact_corrected(startindex:endindex)';
                % smooth if wanted
                if strcmp(cfg.smoothing, 'yes')
                    artifact_corrected_data = smoothdata(artifact_corrected_data);
                end
                % draw the artifact corrected data
                line_artifact_corrected = line(app.ParticipantSkinConductanceAxes, x, artifact_corrected_data); 
                set(line_artifact_corrected, 'color', artifact_corrected_data_colour, 'linewidth', 1.5);
                % add a legend, if requested
                if strcmp(cfg.legend, 'yes')
                    legend_i = legend_i + 1;
                    legenddata(legend_i) = line_artifact_corrected;
                    legendlabels{legend_i} = 'clean sc';
                end
            end

            % the deconvolved data
            if app.project.segment(segment_nr).deconvolved(pp_nr)
                phasicDriver_data   = segment_deconvolved.conductance_phasicDriver(  startindex:endindex)';
                phasic_data         = segment_deconvolved.conductance_phasic(        startindex:endindex)';
                tonic_data          = segment_deconvolved.conductance_tonic(         startindex:endindex)';
                phasicDriver_z_data = segment_deconvolved.conductance_phasicDriver_z(startindex:endindex)';
                phasic_z_data       = segment_deconvolved.conductance_phasic_z(      startindex:endindex)';
                tonic_z_data        = segment_deconvolved.conductance_tonic_z(       startindex:endindex)';
                % smooth if wanted
                if strcmp(cfg.smoothing, 'yes')
                    phasic_data = smoothdata(phasic_data);
                    tonic_data = smoothdata(tonic_data);
                end
                % draw the phasic driver 
                if app.PhasicdriverCheckBox.Value
                    if app.NormalizedCheckBox.Value
                        line_phasicDriver = line(app.ParticipantSkinConductanceAxes, x, phasicDriver_z_data); 
                        set(line_phasicDriver, 'color', phasicDriver_data_colour, 'linewidth', 1.5, 'linestyle', ':');
                        if strcmp(cfg.legend, 'yes')
                            legend_i = legend_i + 1;
                            legenddata(legend_i) = line_phasicDriver;
                            legendlabels{legend_i} = 'phasic driver (z)';
                        end
                    else
                        line_phasicDriver = line(app.ParticipantSkinConductanceAxes, x, phasicDriver_data); 
                        set(line_phasicDriver, 'color', phasicDriver_data_colour, 'linewidth', 1.5);
                        if strcmp(cfg.legend, 'yes')
                            legend_i = legend_i + 1;
                            legenddata(legend_i) = line_phasicDriver;
                            legendlabels{legend_i} = 'phasic driver';
                        end
                    end
                end
                % draw the phasic data
                if app.PhasicscCheckBox.Value
                    if app.NormalizedCheckBox.Value
                        line_phasic = line(app.ParticipantSkinConductanceAxes, x, phasic_z_data); 
                        set(line_phasic, 'color', phasic_data_colour, 'linewidth', 1.5, 'linestyle', ':');
                        if strcmp(cfg.legend, 'yes')
                            legend_i = legend_i + 1;
                            legenddata(legend_i) = line_phasic;
                            legendlabels{legend_i} = 'phasic sc (z)';
                        end
                    else
                        line_phasic = line(app.ParticipantSkinConductanceAxes, x, phasic_data); 
                        set(line_phasic, 'color', phasic_data_colour, 'linewidth', 1.5);
                        if strcmp(cfg.legend, 'yes')
                            legend_i = legend_i + 1;
                            legenddata(legend_i) = line_phasic;
                            legendlabels{legend_i} = 'phasic sc';
                        end
                    end
                end
                % draw the tonic data
                if app.TonicscCheckBox.Value
                    if app.NormalizedCheckBox.Value
                        line_tonic = line(app.ParticipantSkinConductanceAxes, x, tonic_z_data); 
                        set(line_tonic, 'color', tonic_data_colour, 'linewidth', 1.5, 'linestyle', ':');
                        if strcmp(cfg.legend, 'yes')
                            legend_i = legend_i + 1;
                            legenddata(legend_i) = line_tonic;
                            legendlabels{legend_i} = 'tonic sc (z)';
                        end
                    else
                        line_tonic = line(app.ParticipantSkinConductanceAxes, x, tonic_data); 
                        set(line_tonic, 'color', tonic_data_colour, 'linewidth', 1.5);
                        if strcmp(cfg.legend, 'yes')
                            legend_i = legend_i + 1;
                            legenddata(legend_i) = line_tonic;
                            legendlabels{legend_i} = 'tonic sc';
                        end
                    end
                end
            end

            % add a legend, if requested
            if strcmp(cfg.legend, 'yes') && (legend_i > 0)
                legend(app.ParticipantSkinConductanceAxes, legenddata, legendlabels,'FontSize',10, 'Orientation', 'vertical', 'Location', cfg.legendposition, 'NumColumns', cfg.legendnumcolumns);
            end

            % open the project details tab
            app.MainTab.SelectedTab = app.MainTabParticipantGraph;            
        end

        % Button pushed function: DrawParticipantGraph
        function UpdateParticipantGraph(app)
            cfg = [];
            cfg.pp_nr = app.participant_graph.pp_nr;
            cfg.segment_nr = app.participant_graph.segment_nr;
            app.DrawParticipantGraph(cfg);
        end
        
        function ExportProcessedSkinConductanceData(app)

            % for readability of the code
            nof_segments = app.project.nof_segments;
            nof_pps = length(app.project.pp_labels);

            % start with an empty table
            export_table = [];

            for pp_nr = 1:nof_pps
                for segment_nr = 1:nof_segments

                    pp_label = app.project.pp_labels{pp_nr};
                    segment_name = app.project.segment(segment_nr).name;

                    % get the deconvolved data
                    if app.project.segment(segment_nr).deconvolved(pp_nr) && app.project.segment(segment_nr).include(pp_nr)
                        path_filename = fullfile(app.project.processed_data_directory, ['segment_deconvolved_' segment_name '_' pp_label '.mat']);
                        load(path_filename);
                        % segment_deconvolved struct is now loaded with field conductance_phasic and conductance_tonic

                        %  set elapsed time
                        elapsed_time = segment_deconvolved.time;
                        nof_timepoints = length(elapsed_time);
                        % set start time
                        starttime(1:nof_timepoints, 1) = segment_deconvolved.initial_time_stamp_mat;
                        time = datetime(starttime, 'Format', 'dd-MMM-yyyy HH:mm:ss.SSS') + seconds(elapsed_time);
                        % participant label
                        participant(1:nof_timepoints, 1) = {pp_label};
                        % segment
                        segment(1:nof_timepoints, 1) = {segment_name};
                        % get the skin conductance data
                        phasicDriver = segment_deconvolved.conductance_phasicDriver;
                        phasicDriver_z = segment_deconvolved.conductance_phasicDriver_z;
                        phasic = segment_deconvolved.conductance_phasic;
                        phasic_z = segment_deconvolved.conductance_phasic_z;
                        tonic = segment_deconvolved.conductance_tonic;
                        tonic_z = segment_deconvolved.conductance_tonic_z;

                        % combine data in a table
                        temp_table = table(participant, segment, starttime, elapsed_time, time, phasicDriver, phasic, tonic, phasicDriver_z, phasic_z, tonic_z);
                        % temp_table = table(participant, segment, starttime, elapsed_time, time, phasic, tonic, phasic_z, tonic_z);
                        % add this table to the overall table
                        export_table = [export_table; temp_table];
                        % clean up
                        clear starttime time participant segment phasicDriver phasicDriver_z phasic phasic_z tonic tonic_z temp_table;
                    end
                end
            end

            % save the table as a csv file
            filter = {'*.csv'};
            path_filename = fullfile(app.project.output_directory, [app.project.project_name '_processed_skin_conductance.csv']);
            [filename,location] = uiputfile(filter, 'Save processed skin conductance data as', path_filename);
            if ~isequal(filename,0) && ~isequal(location,0)
                writetable(export_table,fullfile(location, filename),'Delimiter',',','QuoteStrings','all');
            end

        end
        
        function updateParticipantData(app, event)
            app.AppendLog('updating participant data ...');
            cfg = [];
            [app.project, msg] = update_participant_data(cfg, app.project);
            app.SaveProject();
            app.populate_participant_table();
            app.AppendLog(msg);% make note of what had been updated
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cfg)
          
            % Hide the busy doing what message
            app.BusyMsg.Visible = "off";

            % Examines the installer info to determine the version number
            % and release date of an application
            if isdeployed
                [~, result] = system('path');
                p = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                % Load the XML file
                xmlFileName=fullfile(p,'..','uninstall','ApplicationInstallerManifest.xml');
                xmlDoc = xmlread(xmlFileName);
                % Get the document element
                docElement = xmlDoc.getDocumentElement();
                nodeList = docElement.getElementsByTagName('Version');
                node = nodeList.item(0);
                app.release.version = char(node.getTextContent());
            else
                fullpath=mfilename('fullpath');
                s=settings;
                if ~isempty(strfind(fullpath,s.matlab.addons.InstallationFolder.ActiveValue)) % If run from app
                    [p,~]=fileparts(fullpath); psplit=strsplit(p,filesep);
                    p=strjoin(psplit(1:find(strcmp(psplit,'Apps'))+1),filesep);
                    if isfolder(fullfile(p,'resources'))
                        xmlFileName=fullfile(p,'resources','addons_core.xml');
                        xmlDoc = xmlread(xmlFileName);
                        % Get the document element
                        docElement = xmlDoc.getDocumentElement();
                        nodeList = docElement.getElementsByTagName('Version');
                        node = nodeList.item(0);
                        app.release.version = char(node.getTextContent());
                    else
                        app.release.version = 'debug';
                    end
                else % If run from MATLAB mlapp file
                    app.release.version = 'debug';
                end
            end
            
            % set the title bar
                app.UIFigure.Name = ['Breda Experience Lab Toolbox (', app.release.version , ')'];

            % show the recent projects in the menubar and on the home tab
            app.show_recent_projects();

            %Make app fullscreen
            drawnow;
            % app.UIFigure.WindowState = 'maximized';

            % === FOR TESTING :: load an existing project at startup to
            % populate the pp table
            % load('C:\projects\PSV\project_PSV.belt', "-mat");
            
            % add the project name to the title bar
            % app.UIFigure.Name = [app.UIFigure.Name, ' - ', project.project_name];


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Event received function: HomeHTML
        function HomeHTMLEventReceived(app, event)
            eventName = event.HTMLEventName;
            fprintf('%s\n', eventName);

            if strcmp(eventName, 'OpenProject')
                app.OpenExistingProject(event);
                % app.OpenExistingProject();
            elseif strcmp(eventName, 'NewProject')
                app.OpenNewProject(event);
            elseif strcmp(eventName, 'RecentProject')
                app.OpenRecentProjectFromHTML(event);
            end

        end

        % Callback function: MenuProjectOpen, openProjectBtn
        function OpenExistingProject(app, event)

                % Let the user select a .belt project file
                [file,location] = uigetfile('*.belt', 'Open a BELT project', 'C:\');
                
                if file % a file was selected

                    % read the project data
                    full_file = fullfile(location, file);

                    % load the project and show the project data
                    app.OpenProject(full_file);

                    % write a message in the log
                    msg = ['existing project ''', app.project.project_name, ''' opened'];
                    app.AppendLog(msg);

                end
            
        end

        % Callback function: MenuProjectNew, newProjectBtn
        function OpenNewProject(app, event)
                app.UIFigure.Name = 'New project';
                app.AppendLog('opening a new project');

                cfg = [];
                cfg.show_input_window = true;
                app.project = [];
                
                % check and create directories, and create the project struct
                app.project = create_new_project(cfg, app.project);

                if isfield(app.project, 'project_name') % verify that user pressed create

                    % Add the relevant participant data to the project struct
                    app.project = add_participant_data(cfg, app.project);

                    % add the bookkeeping part to the project
                    for segment_i = 1:app.project.nof_segments
                        app.project.segment(segment_i).segmented          = zeros(size(app.project.pp_labels));
                        app.project.segment(segment_i).artifact_corrected = zeros(size(app.project.pp_labels));
                        app.project.segment(segment_i).deconvolved        = zeros(size(app.project.pp_labels));
                        app.project.segment(segment_i).include            = ones(size(app.project.pp_labels));
                    end

                    % save the new project
                    app.SaveProject();

                    % load the project and show the project data
                    path_filename = fullfile(app.project.project_directory, ['project_' app.project.project_name '.belt']);
                    app.OpenProject(path_filename);
                end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            % Determine what was clicked on ...
            %   segment_name :: read form the title of the tab = event.Source.Parent.Title 
            %   pp_nr        :: is the row number = event.Indices(1)
            %   action       :: is the column number event.Indices(2) (1=pp label, 2=segment, 3=artifact correct, 4=deconvolve, 5=include)

            % for testing
            % msg = sprintf('Clicked on Segment ''%s'', Participant ''%s'', Column ''%s''.', event.Source.Parent.Title, app.project.pp_labels{indices(1)}, event.Source.ColumnName{indices(2)});
            % app.AppendLog(msg);

            % clear the previous data
            app.BeltArtifactCorrectionComponent.ClearData();

            % determine the segment_nr
            segment_name = event.Source.Parent.Title;
            segment_nr = 0;
            for segment_i = 1:app.project.nof_segments % check all segments
                if strcmp(app.project.segment(segment_i).name, segment_name) % compare segment names
                    segment_nr = segment_i; % segment found
                    break;
                end
            end
            % determine the participant nr and action
            pp_nr = indices(1); % = row nr
            action =indices(2);

            % create the cfg struct
            cfg = [];
            cfg.pp_nr = pp_nr;
            cfg.segment_nr = segment_nr;


            switch action % column nr
                case 1 % pp label
                    app.DrawParticipantGraph(cfg);
                case 2 % segment
                    app.Segmentation(cfg);
                case 3 % artifact correction                    
                    app.ArtifactCorrection(cfg);
                case 4 % deconvolution
                    app.Deconvolution(cfg);
                case 5 % include
                    % toggle the include value (true|false)
                    app.ToggleInclude(cfg);

            end    

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if strcmp(app.MainTab.SelectedTab.Title, 'Artifact Correction')
                cursorXY = app.UIFigure.CurrentPoint;
                cursorX = cursorXY(1) - app.RightPanel.Position(1) - app.MainPanel.Position(1) - app.MainTab.Position(1) - app.MainTabArtifactCorrection.Position(1) - app.BeltArtifactCorrectionComponent.Position(1);
                cursorY = cursorXY(2) - app.RightPanel.Position(2) - app.MainPanel.Position(2) - app.MainTab.Position(2) - app.MainTabArtifactCorrection.Position(2) - app.BeltArtifactCorrectionComponent.Position(2);
                
                cursorXY(1) = cursorX;
                cursorXY(2) = cursorY;
                
                Pointertype = app.BeltArtifactCorrectionComponent.MouseMove(cursorXY);
                app.UIFigure.Pointer = Pointertype;
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if strcmp(app.MainTab.SelectedTab.Title, 'Artifact Correction')
                app.BeltArtifactCorrectionComponent.MouseUp();
            end
        end

        % Callback function: BeltArtifactCorrectionComponent
        function SkipArtifactCorrection(app, event)
            % the current segment will not be saved
            % the next available segment will be opened for artifact correction

            % clear the previous the data
            app.BeltArtifactCorrectionComponent.ClearData();

            % provide feedback
            pp_label = app.project.pp_labels{app.current_cfg.pp_nr};
            segment_name = app.project.segment(app.current_cfg.segment_nr).name;
            msg = sprintf('Data of participant %s segment %s was skipped.', pp_label, segment_name);
            app.AppendLog(msg);
            
            % find the next segment to clean
            next_cfg = app.FindNextSegmentToClean(app.current_cfg);

            if next_cfg.pp_nr > 0
                % store the new numbers in the cfg
                app.current_cfg      = next_cfg;
    
                % highlight the segment in the participant table
                app.highlight_segment(next_cfg);
    
                % start artifact correction of the next segment
                app.ArtifactCorrection(app.current_cfg);
            else
                % open the project details tab
                app.MainTab.SelectedTab = app.MainTabProject;            
            end
            
        end

        % Callback function: BeltArtifactCorrectionComponent
        function StopArtifactCorrection(app, event)
            % indicate no pp is selected
            app.current_cfg.pp_nr = 0;

            % remove the highlight of the current segment in the participant table
            cfg=[];
            cfg.pp_nr = 0;
            app.highlight_segment(cfg);

            % remove the data from the artifact correction tab
            app.BeltArtifactCorrectionComponent.ClearData();
            
            % show the project tab
            app.MainTab.SelectedTab = app.MainTabProject;

        end

        % Callback function: BeltArtifactCorrectionComponent
        function SaveArtifactCorrectedData(app, event)
            % process the artifact corrected data when the [Save] button is pressed

            % Let the user know that the file is being saved
            thandle = app.showBusyMessage("Saving");

            % save the artifact corrected segment
            app.segment_raw.conductance_artifact_corrected = app.BeltArtifactCorrectionComponent.solution;
            [app.project, msg] = save_artifact_corrected_data(app.current_cfg, app.project, app.segment_raw);

            % update the table
            app.populate_participant_table();

            % provide feedback
            app.AppendLog(msg);

            % wait until the busy message is done
            wait(thandle);

            % clear the previous data
            app.BeltArtifactCorrectionComponent.ClearData();

            % find the next segment to clean
            next_cfg = app.FindNextSegmentToClean(app.current_cfg);

            if next_cfg.pp_nr > 0 

                % store the new numbers in the cfg
                app.current_cfg      = next_cfg;
    
                % highlight the segment in the participant table
                app.highlight_segment(next_cfg);
    
                % start artifact correction of the next segment
                app.ArtifactCorrection(app.current_cfg);
            else
                % open the project details tab
                app.MainTab.SelectedTab = app.MainTabProject;            
            end
            
        end

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
            
            % clear the canvas
            cla(app.SkinConductanceAxes);
            % tell the plotter to keep what is already on canvas
            hold(app.SkinConductanceAxes, 'on');

            % In case we need them, these are matlab default colours
            C = orderedcolors("gem12");
            for colour_i = 12:-1:1
                default_colours(colour_i).colour = C(colour_i,:);
            end
            nof_default_colours     = length(default_colours);

            % start a new configuration
            cfg = [];

            % What latencies to plot? What if durations are unequal?
            % Some day we may add a [start end] option
            if app.LatencyAllMenu.Checked
                cfg.latency = 'all'; % [start end]
            elseif app.LatencyTruncateMenu.Checked
                cfg.latency = 'truncate'; % truncates all to the shortest
            end

            % Show the legend? And where?
            if app.LegendNoneMenu.Checked
                cfg.legend = 'no';
            else
                cfg.legend = 'yes';
                if app.LegendTopRightMenu.Checked
                    cfg.legendposition = 'northeast';
                elseif app.LegendTopLeftMenu.Checked
                    cfg.legendposition = 'northwest';
                elseif app.LegendBottomRightMenu.Checked
                    cfg.legendposition = 'southeast';
                elseif app.LegendBottomLeftMenu.Checked
                    cfg.legendposition = 'southwest';
                end
                cfg.legendnumcolumns = 1;
            end

            % Smooth the data?
            if app.SmoothingNoneMenu.Checked
                cfg.smoothing = 'no';
            else
                cfg.smoothing = 'yes';
                if app.Smoothing20Menu.Checked
                    cfg.smoothingwindow = 20;
                elseif app.Smoothing40Menu.Checked
                    cfg.smoothingwindow = 40;
                end
            end

            % Use the Standard data or the Normalized data?
            if app.NormalizeYesMenu.Checked
                cfg.normalize = true; 
            else
                cfg.normalize = false;
            end
           
            % Set the type of data
            switch app.DatatypeDropDown.Value
                case 'Phasic Driver'
                    if cfg.normalize
                        cfg.parameter = 'conductance_phasicDriver_z';
                    else
                        cfg.parameter = 'conductance_phasicDriver';
                    end
                case 'Phasic Data'
                    if cfg.normalize
                        cfg.parameter = 'conductance_phasic_z';
                    else
                        cfg.parameter = 'conductance_phasic';
                    end
                case 'Tonic Data'
                    if cfg.normalize
                        cfg.parameter = 'conductance_tonic_z';
                    else
                        cfg.parameter = 'conductance_tonic';
                    end
            end

            % Shaded area represents:
            switch app.ShadedareaDropDown.Value
                case '95% C.I.'
                    cfg.errorbar = 'ci';
                case 'S.E.M.'
                    cfg.errorbar = 'sem';
                case 'SD'
                    cfg.errorbar = 'sd';
                case 'none'
                    cfg.errorbar = 'none';
            end

            % update title
            Subtitle = [app.DatatypeDropDown.Value];
            if cfg.normalize
                Subtitle = [Subtitle ' Normalized'];
            end
            Subtitle = [Subtitle ' (' app.ShadedareaDropDown.Value ')'];

            app.SkinConductanceAxes.Subtitle.String = Subtitle;

            % internal defaults
            shaded_alpha     = .2;

            % get the number of datasets/segments/conditions
            nof_datasets = app.project.nof_segments;

            % which segments are selected for plotting
            SelectedSegments = {};
            for selected_segment_i = size(app.SelectSegmentsTree.CheckedNodes, 1):-1:1
                SelectedSegments{selected_segment_i} = app.SelectSegmentsTree.CheckedNodes(selected_segment_i).Text;
            end

            % get the data        
            data_set = [];
            data_counter = 0;

            % go through all datasets
            pp_counter=0;
            for segment_i = 1:nof_datasets 
                % if we are plottinga GA, then all data is piled onto the same heap
                if ~app.ShowGrandAverageofselectedconditionsMenu.Checked
                    % but if we plot the conditions seperately, start fresh
                    pp_counter = 0;
                end
                segment_name = app.project.segment(segment_i).name;

                % check whether this segment is checked in the list next to the graph
                if any(strcmp(SelectedSegments, segment_name))

                    % keep track of the shortest segment length
                    shortest_data_duration = Inf; % start with infinity
                    shortest_data_x = [];
                    longest_data_duration = 0; %
                    longest_data_x = [];

                    % for all participants
                    for pp_i = 1:length(app.project.pp_labels)
                        % check whether the data is deconvolved (and set include, so 'use' is checked)
                        if app.project.segment(segment_i).deconvolved(pp_i) && app.project.segment(segment_i).include(pp_i)
                            pp_counter = pp_counter + 1;
                            if pp_counter == 1 
                                data_counter = data_counter + 1;
                            end
                            pp_label = app.project.pp_labels{pp_i};
                            path_filename = fullfile(app.project.processed_data_directory, ['segment_deconvolved_' segment_name '_' pp_label '.mat']);
                            load(path_filename);
                            data_set(data_counter).data(pp_counter) = segment_deconvolved;
                            if length(segment_deconvolved.time) < shortest_data_duration
                                shortest_data_duration = length(segment_deconvolved.time);
                                shortest_data_x = segment_deconvolved.time';
                            end
                            if length(segment_deconvolved.time) > longest_data_duration
                                longest_data_duration = length(segment_deconvolved.time);
                                longest_data_x = segment_deconvolved.time';
                            end
                        end
                    end
                    % store the shortest and longest duration of data
                    % within this set
                    data_set(data_counter).shortest_duration = shortest_data_duration;
                    data_set(data_counter).longest_duration  = longest_data_duration;
                    data_set(data_counter).shortest_x        = shortest_data_x;
                    data_set(data_counter).longest_x         = longest_data_x;

                    if pp_counter > 1 
                        if app.ShowGrandAverageofselectedconditionsMenu.Checked
                            cfg.legendlabels{data_counter} = 'Grand average';
                            cfg.colours(data_counter).colour = default_colours(1).colour;
                        else
                            cfg.legendlabels{data_counter} = segment_name;
                            cfg.colours(data_counter).colour = default_colours(rem((segment_i-1), nof_default_colours)+1).colour;
                        end
                    end
                end
            end

            % just in case there is no data to show yet
            if isempty(data_set)
                app.AppendLog('There is no data to plot yet. Process some data first and come back here when you''ve done the split (deconvolution) part.')
                return;
            end

            % get the number of datasets to plot
            nof_datasets_to_plot = length(data_set);

            % determine which latency to plot
            % cfg.latency = 'all'; plots the whole data for each segment, even if these are different
            % cfg.latency = 'truncate'; plots the latencies of the shortest segment, truncates longer ones
            % cfg.latency = [start end]; plots specifid latencies

            %  set x (time)
            if strcmp(cfg.latency, 'truncate')

                % Truncate all datasets to the shortest one
                % Find the dataset with the shortest duration
                % Note that each dataset is as long as the longest data in it
                shortest_dataset_duration = Inf; % start with infinity
                for dataset_i=1:nof_datasets_to_plot
                    if data_set(dataset_i).longest_duration < shortest_dataset_duration
                        shortest_dataset_duration = data_set(dataset_i).longest_duration;
                        x = data_set(dataset_i).longest_x;
                    end
                end
                startindex = 1;
                endindex = shortest_data_duration;

            elseif strcmp(cfg.latency, 'all')
                % use the whole latency range
                % Find the dataset with the logest data duration
                longest_dataset_duration = 0; % start with infinity
                for dataset_i=1:nof_datasets_to_plot
                    if data_set(dataset_i).longest_duration > longest_dataset_duration
                        longest_dataset_duration = data_set(dataset_i).longest_duration;
                        x = data_set(dataset_i).longest_x;
                    end
                end
                startindex = 1;
                endindex = longest_data_duration;
            else
                % Check whether latency is valid
                if ~isnumeric(cfg.latency) % latency should be two numbers
                    uialert(app.UIFigure,'Provided latency range is not valid. Use [start end] in seconds. Type help plot_timeseries_data for further info.', 'Error');
                end
                if cfg.latency(1) < min(x) % the start latency cannot be before the start of the data
                    uialert(app.UIFigure,'Provided latency range is not valid. The start time is smaller then the start of the data. Type help plot_timeseries_data for further info.', 'Error');
                end
                if cfg.latency(2) > shortest_data_duration % the end latency cannot be after the end of the data
                    uialert(app.UIFigure,'Provided latency range is not valid. The end time exceed the time range of the data. Type help plot_timeseries_data for further info.', 'Error');
                end
                % latency values appear in order
                starttime = cfg.latency(1);
                endtime   = cfg.latency(2);
                % find the start and end indices of these times
                startindex = find(x >= starttime, 1);
                endindex   = find(x >= endtime, 1);
            end
            % get the number of timepoints
            nof_timepoints = length(x);

             
            % for each of the datasets, determine the mean (averaged over pps),
            % and the lower/upper limits of the shaded area representing the s.e.m.
            for dataset_i = nof_datasets_to_plot:-1:1

                % first, create a matrix of NaNs
                nof_pps_dataset     = length(data_set(dataset_i).data);
                x_dataset = data_set(dataset_i).longest_x;
                nof_samples_dataset = length(x_dataset);
                dataset(dataset_i).data    = NaN(nof_pps_dataset, nof_samples_dataset);

                % then, fill that matrix with the participant data,
                % each row will hold the data of one participant
                for pp_i = 1:nof_pps_dataset
                    % the_data = varargin{1}(1).(cfg.parameter);
                    % if length(data_set(dataset_i).data(pp_i).(cfg.parameter)) < nof_samples_dataset
                         dataset(dataset_i).data(pp_i, 1:length(data_set(dataset_i).data(pp_i).(cfg.parameter))-startindex+1) =  data_set(dataset_i).data(pp_i).(cfg.parameter)(startindex:end)';
                    % else
                    %     % this should not happen
                    %      dataset(dataset_i).data(pp_i, :) =  data_set(dataset_i).data(pp_i).(cfg.parameter)(startindex:nof_samples_dataset)';
                    % end
                end

                % remove columns from the data that contain only NaNs (the
                % data is shorter than the logest duration data)
                 dataset(dataset_i).data =  dataset(dataset_i).data(:, ~isnan(sum( dataset(dataset_i).data, "omitnan"))); % this should not be necessary

            end


            for dataset_i = nof_datasets_to_plot:-1:1

                the_data =  dataset(dataset_i).data;


                % mean EEG amplitude, averaged over pps
                y(dataset_i).mean = mean(squeeze(the_data),1,"omitnan");

                % smoothing
                if strcmp(cfg.smoothing, 'yes')
                    y(dataset_i).mean = smoothdata(y(dataset_i).mean, 'movmean', cfg.smoothingwindow);
                end

                % e = sd|sem|ci|none
                N = size(the_data, 1);
                if N < 2 % there is no standard deviation for data of only a single participant
                    y(dataset_i).e = zeros(1, size(squeeze(the_data),2));
                else
                    switch cfg.errorbar
                        case 'sd'   % standard deviation
                            y(dataset_i).e = std(squeeze(the_data),1,"omitnan");
                        case 'sem'  % the standard error of the mean
                            y(dataset_i).e = std(squeeze(the_data),1,"omitnan") ./ sqrt(N);
                        case 'ci'   % 95% confidence interval
                            % tinv(0.975, N-1) gets the value from the t-distribution corresponding to p = .975 for a given N
                            y(dataset_i).e = tinv(0.975, N-1) .* (std(squeeze(the_data),1,"omitnan") ./ sqrt(N));
                        case 'none' % no shaded area
                            y(dataset_i).e = zeros(1, size(squeeze(the_data),2));
                        otherwise   % user provided an unknow error-bar type. Display a warning message.
                            app.AppendLog('WARNING: unknown error-bar type "%s", shaded area cannot be plotted', errorbar);
                            y(dataset_i).e = zeros(1, size(squeeze(the_data),2));
                    end
                end

                % lower and upper border of shaded area: mean +- s.e.m.
                y(dataset_i).low  = y(dataset_i).mean - y(dataset_i).e; % lower bound
                y(dataset_i).high = y(dataset_i).mean + y(dataset_i).e; % upper bound
            end

            % first draw the shaded areas
            for dataset_i=nof_datasets_to_plot:-1:1
                x_dataset = data_set(dataset_i).longest_x;
                hp(dataset_i) = patch(app.SkinConductanceAxes, 'XData', [x_dataset, x_dataset(end:-1:1), x_dataset(1)], 'YData', [y(dataset_i).low, y(dataset_i).high(end:-1:1), y(dataset_i).low(1)]); % shaded area of SE around the mean
                if size(cfg.colours, 1) == 0
                    set(hp(dataset_i), 'edgecolor', 'none', 'FaceAlpha', shaded_alpha);
                else
                    set(hp(dataset_i), 'facecolor', cfg.colours(dataset_i).colour, 'edgecolor', 'none', 'FaceAlpha', shaded_alpha);
                end
            end

            % then draw the x en y axis
            x_axis_line = line(app.SkinConductanceAxes, [x(1), x(end)],[0, 0]); % x = 0 axis
            y_axis_line = line(app.SkinConductanceAxes, [0,0],[min(y(1).mean), max(y(1).mean)]); % y = 0 axis
            set(x_axis_line, 'color', [0.5 0.5 0.5],'linewidth',1.0);
            set(y_axis_line, 'color', [0.5 0.5 0.5],'linewidth',1.0);
            xlim(app.SkinConductanceAxes,[x(1), x(end)]);

            % finally draw the mean amplitudes of each dataset
            for dataset_i = nof_datasets_to_plot:-1:1
                x_dataset = data_set(dataset_i).longest_x;
                erp(dataset_i) = line(app.SkinConductanceAxes, x_dataset(1:length(y(dataset_i).mean)), y(dataset_i).mean); % mean amplitude
                if size(cfg.colours, 1) == 0
                    set(erp(dataset_i), 'linewidth', 2);
                else
                    set(erp(dataset_i), 'color', cfg.colours(dataset_i).colour,'linewidth', 1.5);
                end
            end

            % add a legend, if requested
            if strcmp(cfg.legend, 'yes')
                legenddata = zeros(1, nof_datasets_to_plot);
                for dataset_i=1:nof_datasets_to_plot
                    legenddata(dataset_i) = erp(dataset_i);
                end
                legend(app.SkinConductanceAxes, legenddata, cfg.legendlabels,'FontSize',10, 'Orientation', 'vertical', 'Location', cfg.legendposition, 'NumColumns', cfg.legendnumcolumns);
            else
                legend(app.SkinConductanceAxes, "off");
            end

        end

        % Button pushed function: SavegraphButton
        function SavegraphButtonPushed(app, event)
            filter = {'*.png';'*.jpg';'*.tif';'*.pdf';'*.eps'};
            path_filename = fullfile(app.project.output_directory, [app.project.project_name '_meanSkinConductance_' app.SkinConductanceAxes.Subtitle.String '.png']);
            [filename,location] = uiputfile(filter, 'Save graph as', path_filename);
            if ~isequal(filename,0) && ~isequal(location,0)
                exportgraphics(app.SkinConductanceAxes,[location filename]);
                app.AppendLog(['Graph saved as ' path_filename]);
           end

        end

        % Value changed function: CleanscCheckBox
        function CleanscCheckBoxValueChanged(app, event)
            app.UpdateParticipantGraph();
        end

        % Value changed function: PhasicdriverCheckBox
        function PhasicdriverCheckBoxValueChanged(app, event)
            app.UpdateParticipantGraph();
        end

        % Value changed function: PhasicscCheckBox
        function PhasicscCheckBoxValueChanged(app, event)
            app.UpdateParticipantGraph();
        end

        % Value changed function: TonicscCheckBox
        function TonicscCheckBoxValueChanged(app, event)
            app.UpdateParticipantGraph();
        end

        % Value changed function: RawscCheckBox
        function RawscCheckBoxValueChanged(app, event)
            app.UpdateParticipantGraph();
        end

        % Value changed function: NormalizedCheckBox
        function NormalizedCheckBoxValueChanged(app, event)
            app.UpdateParticipantGraph();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.ExportProcessedSkinConductanceData();
        end

        % Event received function: ProjectHTML
        function ProjectHTMLEventReceived(app, event)
            eventName = event.HTMLEventName;
            eventData = event.HTMLEventData;
            if strcmp(eventName, 'updateParticipantData')
                app.updateParticipantData(event);
            end
        end

        % Menu selected function: LatencyAllMenu
        function LatencyAllMenuSelected(app, event)
            app.LatencyTruncateMenu.Checked = "off";
            app.LatencyAllMenu.Checked = "on";
            app.LatencySpecificRangeMenu.Checked = "off";

        end

        % Menu selected function: LatencyTruncateMenu
        function LatencyTruncateMenuSelected(app, event)
            app.LatencyTruncateMenu.Checked = "on";
            app.LatencyAllMenu.Checked = "off";
            app.LatencySpecificRangeMenu.Checked = "off";
        end

        % Menu selected function: NormalizeYesMenu
        function NormalizeYesMenuSelected(app, event)
            app.NormalizeYesMenu.Checked = "on";
            app.NormalizeNoMenu.Checked = "off";
        end

        % Menu selected function: NormalizeNoMenu
        function NormalizeNoMenuSelected(app, event)
            app.NormalizeYesMenu.Checked = "off";
            app.NormalizeNoMenu.Checked = "on";
        end

        % Menu selected function: LegendBottomRightMenu
        function LegendBottomRightMenuSelected(app, event)
            app.LegendBottomRightMenu.Checked = "on"; % checked
            app.LegendBottomLeftMenu.Checked  = "off";
            app.LegendTopRightMenu.Checked    = "off";
            app.LegendTopLeftMenu.Checked     = "off";
            app.LegendNoneMenu.Checked        = "off";
        end

        % Menu selected function: LegendTopRightMenu
        function LegendTopRightMenuSelected(app, event)
            app.LegendBottomRightMenu.Checked = "off";
            app.LegendBottomLeftMenu.Checked  = "off";
            app.LegendTopRightMenu.Checked    = "on"; % checked
            app.LegendTopLeftMenu.Checked     = "off";
            app.LegendNoneMenu.Checked        = "off";
        end

        % Menu selected function: LegendTopLeftMenu
        function LegendTopLeftMenuSelected(app, event)
            app.LegendBottomRightMenu.Checked = "off";
            app.LegendBottomLeftMenu.Checked  = "off";
            app.LegendTopRightMenu.Checked    = "off";
            app.LegendTopLeftMenu.Checked     = "on"; % checked
            app.LegendNoneMenu.Checked        = "off";
        end

        % Menu selected function: LegendBottomLeftMenu
        function LegendBottomLeftMenuSelected(app, event)
            app.LegendBottomRightMenu.Checked = "off";
            app.LegendBottomLeftMenu.Checked  = "on"; % checked
            app.LegendTopRightMenu.Checked    = "off";
            app.LegendTopLeftMenu.Checked     = "off";
            app.LegendNoneMenu.Checked        = "off";
        end

        % Menu selected function: LegendNoneMenu
        function LegendNoneMenuSelected(app, event)
            app.LegendBottomRightMenu.Checked = "off";
            app.LegendBottomLeftMenu.Checked  = "off";
            app.LegendTopRightMenu.Checked    = "off";
            app.LegendTopLeftMenu.Checked     = "off";
            app.LegendNoneMenu.Checked        = "on"; % checked
        end

        % Menu selected function: SmoothingNoneMenu
        function SmoothingNoneMenuSelected(app, event)
            app.Smoothing20Menu.Checked   = "off";
            app.Smoothing40Menu.Checked   = "off";
            app.SmoothingNoneMenu.Checked = "on";  % checked
        end

        % Menu selected function: Smoothing20Menu
        function Smoothing20MenuSelected(app, event)
            app.Smoothing20Menu.Checked   = "on"; % checked
            app.Smoothing40Menu.Checked   = "off";
            app.SmoothingNoneMenu.Checked = "off";  
        end

        % Menu selected function: Smoothing40Menu
        function Smoothing40MenuSelected(app, event)
            app.Smoothing20Menu.Checked   = "off";
            app.Smoothing40Menu.Checked   = "on"; % checked
            app.SmoothingNoneMenu.Checked = "off";
        end

        % Menu selected function: 
        % ShowindividualaveragesofselectedconditionsMenu
        function ShowindividualaveragesofselectedconditionsMenuSelected(app, event)
            app.ShowGrandAverageofselectedconditionsMenu.Checked   = "off";
            app.ShowindividualaveragesofselectedconditionsMenu.Checked   = "on"; % checked

        end

        % Menu selected function: ShowGrandAverageofselectedconditionsMenu
        function ShowGrandAverageofselectedconditionsMenuSelected(app, event)
            app.ShowGrandAverageofselectedconditionsMenu.Checked   = "on"; % checked
            app.ShowindividualaveragesofselectedconditionsMenu.Checked   = "off";
        end

        % Button pushed function: recentProject1Btn
        function recentProject1BtnButtonPushed(app, event)
            app.OpenRecentProject(1);
        end

        % Button pushed function: recentProject2Btn
        function recentProject2BtnButtonPushed(app, event)
            app.OpenRecentProject(2);
        end

        % Button pushed function: recentProject3Btn
        function recentProject3BtnButtonPushed(app, event)
            app.OpenRecentProject(3);
        end

        % Button pushed function: recentProject4Btn
        function recentProject4BtnButtonPushed(app, event)
            app.OpenRecentProject(4);
        end

        % Button pushed function: recentProject5Btn
        function recentProject5BtnButtonPushed(app, event)
            app.OpenRecentProject(5);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {595, 595};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'BeltApp';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'BELTapp_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.MenuProject = uimenu(app.UIFigure);
            app.MenuProject.Text = 'Project';
            app.MenuProjectNew = uimenu(app.MenuProject);
            app.MenuProjectNew.MenuSelectedFcn = createCallbackFcn(app, @OpenNewProject, true);
            app.MenuProjectNew.Accelerator = 'N';
            app.MenuProjectNew.Text = 'New';
            app.MenuProjectOpen = uimenu(app.MenuProject);
            app.MenuProjectOpen.MenuSelectedFcn = createCallbackFcn(app, @OpenExistingProject, true);
            app.MenuProjectOpen.Accelerator = 'O';
            app.MenuProjectOpen.Text = 'Open';
            app.MenuProjectRecent = uimenu(app.MenuProject);
            app.MenuProjectRecent.Text = 'Recent';
            app.MenuProjectClose = uimenu(app.MenuProject);
            app.MenuProjectClose.Text = 'Close';
            app.MenuSettings = uimenu(app.UIFigure);
            app.MenuSettings.Text = 'Settings';
            app.GraphMenu = uimenu(app.MenuSettings);
            app.GraphMenu.Text = 'Graph';
            app.LatencyMenu = uimenu(app.GraphMenu);
            app.LatencyMenu.Text = 'Latency';
            app.LatencyAllMenu = uimenu(app.LatencyMenu);
            app.LatencyAllMenu.MenuSelectedFcn = createCallbackFcn(app, @LatencyAllMenuSelected, true);
            app.LatencyAllMenu.Checked = 'on';
            app.LatencyAllMenu.Text = 'All';
            app.LatencyTruncateMenu = uimenu(app.LatencyMenu);
            app.LatencyTruncateMenu.MenuSelectedFcn = createCallbackFcn(app, @LatencyTruncateMenuSelected, true);
            app.LatencyTruncateMenu.Text = 'Truncate';
            app.LatencySpecificRangeMenu = uimenu(app.LatencyMenu);
            app.LatencySpecificRangeMenu.Enable = 'off';
            app.LatencySpecificRangeMenu.Text = 'Specific Range';
            app.NormalizeMenu = uimenu(app.GraphMenu);
            app.NormalizeMenu.Text = 'Normalize';
            app.NormalizeNoMenu = uimenu(app.NormalizeMenu);
            app.NormalizeNoMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalizeNoMenuSelected, true);
            app.NormalizeNoMenu.Checked = 'on';
            app.NormalizeNoMenu.Text = 'No';
            app.NormalizeYesMenu = uimenu(app.NormalizeMenu);
            app.NormalizeYesMenu.MenuSelectedFcn = createCallbackFcn(app, @NormalizeYesMenuSelected, true);
            app.NormalizeYesMenu.Text = 'Yes';
            app.LegendMenu = uimenu(app.GraphMenu);
            app.LegendMenu.Text = 'Legend';
            app.LegendTopRightMenu = uimenu(app.LegendMenu);
            app.LegendTopRightMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendTopRightMenuSelected, true);
            app.LegendTopRightMenu.Checked = 'on';
            app.LegendTopRightMenu.Text = 'Top Right';
            app.LegendBottomRightMenu = uimenu(app.LegendMenu);
            app.LegendBottomRightMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendBottomRightMenuSelected, true);
            app.LegendBottomRightMenu.Text = 'Bottom Right';
            app.LegendTopLeftMenu = uimenu(app.LegendMenu);
            app.LegendTopLeftMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendTopLeftMenuSelected, true);
            app.LegendTopLeftMenu.Text = 'Top Left';
            app.LegendBottomLeftMenu = uimenu(app.LegendMenu);
            app.LegendBottomLeftMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendBottomLeftMenuSelected, true);
            app.LegendBottomLeftMenu.Text = 'Bottom Left';
            app.LegendNoneMenu = uimenu(app.LegendMenu);
            app.LegendNoneMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendNoneMenuSelected, true);
            app.LegendNoneMenu.Text = 'None';
            app.SmoothingMenu = uimenu(app.GraphMenu);
            app.SmoothingMenu.Text = 'Smoothing';
            app.SmoothingNoneMenu = uimenu(app.SmoothingMenu);
            app.SmoothingNoneMenu.MenuSelectedFcn = createCallbackFcn(app, @SmoothingNoneMenuSelected, true);
            app.SmoothingNoneMenu.Checked = 'on';
            app.SmoothingNoneMenu.Text = 'None';
            app.Smoothing20Menu = uimenu(app.SmoothingMenu);
            app.Smoothing20Menu.MenuSelectedFcn = createCallbackFcn(app, @Smoothing20MenuSelected, true);
            app.Smoothing20Menu.Text = '20';
            app.Smoothing40Menu = uimenu(app.SmoothingMenu);
            app.Smoothing40Menu.MenuSelectedFcn = createCallbackFcn(app, @Smoothing40MenuSelected, true);
            app.Smoothing40Menu.Text = '40';
            app.GrandAverageMenu = uimenu(app.GraphMenu);
            app.GrandAverageMenu.Text = 'Grand Average';
            app.ShowGrandAverageofselectedconditionsMenu = uimenu(app.GrandAverageMenu);
            app.ShowGrandAverageofselectedconditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowGrandAverageofselectedconditionsMenuSelected, true);
            app.ShowGrandAverageofselectedconditionsMenu.Text = 'Show Grand Average of selected conditions';
            app.ShowindividualaveragesofselectedconditionsMenu = uimenu(app.GrandAverageMenu);
            app.ShowindividualaveragesofselectedconditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowindividualaveragesofselectedconditionsMenuSelected, true);
            app.ShowindividualaveragesofselectedconditionsMenu.Checked = 'on';
            app.ShowindividualaveragesofselectedconditionsMenu.Text = 'Show individual averages of selected conditions';
            app.MenuHelp = uimenu(app.UIFigure);
            app.MenuHelp.Text = 'Help';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Segment';
            app.UITable = uitable(app.Tab1);
            app.UITable.ColumnName = {'Participant'; 'Cut'; 'Clean'; 'Split'; 'Use'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayoutTopLeft = uigridlayout(app.GridLayout3);
            app.projectnameLabel = uilabel(app.GridLayoutTopLeft);
            app.projectnameLabel.Text = 'project name';
            app.RightPanel = uipanel(app.GridLayout);
            app.MainPanel = uipanel(app.RightPanel);
            app.MainPanel.Title = 'Main';
            app.GridLayout4 = uigridlayout(app.MainPanel);
            app.LogPanel = uipanel(app.GridLayout4);
            app.LogPanel.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogPanel);
            app.LogTextArea.Value = {'Starting log ...'};
            app.MainTab = uitabgroup(app.GridLayout4);
            app.MainTabHome = uitab(app.MainTab);
            app.MainTabHome.Title = 'Home';
            app.GridLayout5 = uigridlayout(app.MainTabHome);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.RecentprojectsLabel = uilabel(app.GridLayout7);
            app.RecentprojectsLabel.Text = 'Recent projects:';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.newProjectBtn = uibutton(app.GridLayout6, 'push');
            app.newProjectBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenNewProject, true);
            app.newProjectBtn.Text = 'NEW PROJECT';
            app.openProjectBtn = uibutton(app.GridLayout6, 'push');
            app.openProjectBtn.ButtonPushedFcn = createCallbackFcn(app, @OpenExistingProject, true);
            app.openProjectBtn.Text = 'OPEN PROJECT';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.recentProject1Btn = uibutton(app.GridLayout8, 'push');
            app.recentProject1Btn.ButtonPushedFcn = createCallbackFcn(app, @recentProject1BtnButtonPushed, true);
            app.recentProject1Btn.Text = 'PROJECT 1';
            app.recentProject2Btn = uibutton(app.GridLayout8, 'push');
            app.recentProject2Btn.ButtonPushedFcn = createCallbackFcn(app, @recentProject2BtnButtonPushed, true);
            app.recentProject2Btn.Text = 'PROJECT 2';
            app.recentProject3Btn = uibutton(app.GridLayout8, 'push');
            app.recentProject3Btn.ButtonPushedFcn = createCallbackFcn(app, @recentProject3BtnButtonPushed, true);
            app.recentProject3Btn.Text = 'PROJECT 3';
            app.recentProject4Btn = uibutton(app.GridLayout8, 'push');
            app.recentProject4Btn.ButtonPushedFcn = createCallbackFcn(app, @recentProject4BtnButtonPushed, true);
            app.recentProject4Btn.Text = 'PROJECT 4';
            app.recentProject5Btn = uibutton(app.GridLayout8, 'push');
            app.recentProject5Btn.ButtonPushedFcn = createCallbackFcn(app, @recentProject5BtnButtonPushed, true);
            app.recentProject5Btn.Text = 'PROJECT 5';
            app.HomeHTML = uihtml(app.GridLayout5);
            app.HomeHTML.HTMLSource = 'BeltAppHome.html';
            app.HomeHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @HomeHTMLEventReceived, true);
            app.BusyMsg = uilabel(app.MainTabHome);
            app.BusyMsg.Text = 'What is going on ...?';
            app.MainTabProject = uitab(app.MainTab);
            app.MainTabProject.Title = 'Project';
            app.ProjectHTML = uihtml(app.MainTabProject);
            app.ProjectHTML.HTMLSource = 'BeltAppProject.html';
            app.ProjectHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ProjectHTMLEventReceived, true);
            app.MainTabSegmentation = uitab(app.MainTab);
            app.MainTabSegmentation.Title = 'Segmentation';
            app.SegmentationHTML = uihtml(app.MainTabSegmentation);
            app.SegmentationHTML.HTMLSource = 'BeltAppSegmentation.html';
            app.MainTabArtifactCorrection = uitab(app.MainTab);
            app.MainTabArtifactCorrection.Title = 'Artifact Correction';
            app.BeltArtifactCorrectionComponent = BeltArtifactCorrectionComponent(app.MainTabArtifactCorrection);
            app.BeltArtifactCorrectionComponent.ArtifactDragging = [];
            app.BeltArtifactCorrectionComponent.SaveArtifactCorrectedDataFcn = createCallbackFcn(app, @SaveArtifactCorrectedData, true);
            app.BeltArtifactCorrectionComponent.SkipArtifactCorrectionFcn = createCallbackFcn(app, @SkipArtifactCorrection, true);
            app.BeltArtifactCorrectionComponent.StopArtifactCorrectionFcn = createCallbackFcn(app, @StopArtifactCorrection, true);
            app.MainTabDeconvolution = uitab(app.MainTab);
            app.MainTabDeconvolution.Title = 'Deconvolution';
            app.DeconvolutionHTML = uihtml(app.MainTabDeconvolution);
            app.DeconvolutionHTML.HTMLSource = 'BeltAppDeconvolution.html';
            app.MainTabParticipantGraph = uitab(app.MainTab);
            app.MainTabParticipantGraph.Title = 'Participant Graph';
            app.ParticipantSkinConductanceAxes = uiaxes(app.MainTabParticipantGraph);
            title(app.ParticipantSkinConductanceAxes, 'Skin Conductance')
            xlabel(app.ParticipantSkinConductanceAxes, 'Time (s)')
            ylabel(app.ParticipantSkinConductanceAxes, 'Skin Conductance (\muS)')
            zlabel(app.ParticipantSkinConductanceAxes, 'Z')
            subtitle(app.ParticipantSkinConductanceAxes, '(segmentname, pplabel)')
            app.RawscCheckBox = uicheckbox(app.MainTabParticipantGraph);
            app.RawscCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawscCheckBoxValueChanged, true);
            app.RawscCheckBox.Text = 'Raw sc';
            app.RawscCheckBox.Value = true;
            app.TonicscCheckBox = uicheckbox(app.MainTabParticipantGraph);
            app.TonicscCheckBox.ValueChangedFcn = createCallbackFcn(app, @TonicscCheckBoxValueChanged, true);
            app.TonicscCheckBox.Text = 'Tonic sc';
            app.TonicscCheckBox.Value = true;
            app.PhasicscCheckBox = uicheckbox(app.MainTabParticipantGraph);
            app.PhasicscCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhasicscCheckBoxValueChanged, true);
            app.PhasicscCheckBox.Text = 'Phasic sc';
            app.PhasicscCheckBox.Value = true;
            app.PhasicdriverCheckBox = uicheckbox(app.MainTabParticipantGraph);
            app.PhasicdriverCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhasicdriverCheckBoxValueChanged, true);
            app.PhasicdriverCheckBox.Text = 'Phasic driver';
            app.PhasicdriverCheckBox.Value = true;
            app.NormalizedCheckBox = uicheckbox(app.MainTabParticipantGraph);
            app.NormalizedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizedCheckBoxValueChanged, true);
            app.NormalizedCheckBox.Text = 'Normalized';
            app.CleanscCheckBox = uicheckbox(app.MainTabParticipantGraph);
            app.CleanscCheckBox.ValueChangedFcn = createCallbackFcn(app, @CleanscCheckBoxValueChanged, true);
            app.CleanscCheckBox.Text = 'Clean sc';
            app.CleanscCheckBox.Value = true;
            app.MainTabGraph = uitab(app.MainTab);
            app.MainTabGraph.Title = 'Graph';
            app.SkinConductanceAxes = uiaxes(app.MainTabGraph);
            title(app.SkinConductanceAxes, 'Skin Conductance')
            xlabel(app.SkinConductanceAxes, 'Time (s)')
            ylabel(app.SkinConductanceAxes, 'Skin Conductance (\muS)')
            zlabel(app.SkinConductanceAxes, 'Z')
            subtitle(app.SkinConductanceAxes, '(phasic driver)')
            app.UpdateGraphButton = uibutton(app.MainTabGraph, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update graph';
            app.SavegraphButton = uibutton(app.MainTabGraph, 'push');
            app.SavegraphButton.ButtonPushedFcn = createCallbackFcn(app, @SavegraphButtonPushed, true);
            app.SavegraphButton.Text = 'Save graph';
            app.DatatypeDropDownLabel = uilabel(app.MainTabGraph);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.MainTabGraph);
            app.DatatypeDropDown.Items = {'Phasic Driver', 'Phasic Data', 'Tonic Data'};
            app.DatatypeDropDown.Value = 'Phasic Data';
            app.ShadedareaDropDownLabel = uilabel(app.MainTabGraph);
            app.ShadedareaDropDownLabel.Text = 'Shaded area';
            app.ShadedareaDropDown = uidropdown(app.MainTabGraph);
            app.ShadedareaDropDown.Items = {'95% C.I.', 'S.E.M.', 'SD', 'none'};
            app.ShadedareaDropDown.Value = '95% C.I.';
            app.SelectSegmentsTree = uitree(app.MainTabGraph, 'checkbox');
            app.Segment1Node = uitreenode(app.SelectSegmentsTree);
            app.Segment1Node.Text = 'Segment1';
            app.MainTabExport = uitab(app.MainTab);
            app.MainTabExport.Title = 'Export';
            app.ExportButton = uibutton(app.MainTabExport, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeltApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end