classdef BeltGPSArtifactCorrectionComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        GeoAxesGridLayout           matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        AnimatePanel                matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        SlowerButton                matlab.ui.control.Button
        FasterButton                matlab.ui.control.Button
        StepForwardButton           matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        StopAnimationButton         matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        StepBackwardButton          matlab.ui.control.Button
        FindPanel                   matlab.ui.container.Panel
        AccuracyLimit               matlab.ui.control.NumericEditField
        meterEditField_2Label       matlab.ui.control.Label
        JumpLimit                   matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        AccuracyButton              matlab.ui.control.Button
        JumpsButton                 matlab.ui.control.Button
        ZoomPanel                   matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        ZoomInButton                matlab.ui.control.Button
        ZoomOutButton               matlab.ui.control.Button
        ZoomHomeButton              matlab.ui.control.Button
        ActionsPanel_2              matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        DeleteSelectionButton       matlab.ui.control.Button
        InterpolateSelectionButton  matlab.ui.control.Button
        UndoButton                  matlab.ui.control.Button
        RedoButton                  matlab.ui.control.Button
        ConclusionPanel             matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        StopButton                  matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        SelectionPanel              matlab.ui.container.Panel
        RestoreAllButton            matlab.ui.control.Button
        RestoreSelectionButton      matlab.ui.control.Button
        SelectNoneButton            matlab.ui.control.Button
        SelectAllButton             matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        NextCleaning
        SaveAndNextCleaning % save the curent data first before moving to the next
        SaveArtifactCorrectedData
        SaveCleanedData
        SkipArtifactCorrection
        StopArtifactCorrection
        StopCleaning
    end

    properties (Access = private)
        GPSAxes  matlab.graphics.axis.GeographicAxes % Area to plot the GPS data
    end
    
    properties (Access = public)
        AnimationCounter % counts the number of animation updates since start
        AnimationDot % dot that moves over the GPS data
        AnimationIndex % the current point of the GPS animation
        AnimationIsRunning logical = false; % indicates whether the GPS animation is running or not
        AnimationSpeed double = 4; % how fast does the GPS animation run (1-5)
        AnimationStatus % is the animation 'Playing', 'Paused', or 'Stopped'?
        ControlKeyDown logical = false; % is the control key currently pressed
        DragMapStartMapCenter % location of the map canter when dragging started
        DragMapStartXY % start location of dragging the map in XY pixels
        GPSAxesDragging string = 'none'; % is mouse dragging in progress ('none', 'zoom', 'datapoint', 'map')
        GPSdata_clean % array of the solution data
        GPSdata_hover_Idx % the datapoint that the mouse currently hovers over
        GPSdata_near_cursor % boolean array whther datapoints are near the cursor position
        GPSdata_raw % array of the original data
        GPSdata_selected % array to keep track of which datapoints are selected
        GPSdata_selected_datapoint_idx % the indeces of the GPS datapoint that are selected, in the order in which they were added
        HoverOverDataDot %  a white circle that is drawn over the dot that is nearest the cursor
        HoverOverDataDotLat % Latitide of hovered datapoint
        HoverOverDataDotLon % Longitude of hovered datapoint
        SelectionRectangleEnd % end position of the selection rectangle
        SelectionRectangleStart % start position of the selectuon rectangle
        SelectionRectangleVisible logical = false; % show the selection rectangle (true/false)
        SelectionType % determines whether the new selection is added to the existing one (Shift behaviour) or the XOR is taken (Ctrl behaviour)
        ShiftKeyDown logical = false; % Whether the Shift key is pressed or not
        ToolTip2 % Shows the time value of the cursor position
        UnsavedChanges % keeps track of whether thare are unsaved changes
        ZoomRectangle % the rectangle to zoom to
        ZoomRectangleEnd % end position of the zoom rectangle
        ZoomRectangleStart % start location of the rectangle
        ZoomRectangleVisible logical = false; % Show the rectangle shape to indicate zooming range
        addartifactapp % connection to the add artifact window
        artifacts % struct with all artifacts ('start','end','solution','custom')
        cfg % The configuration struct that was passed by the parent app
        cursorIsNearData % whether the cursor is near data
        drawvalidationdata = 1;
        focus_lat % the lowest time value of a focussed window
        focus_lon % the highest time value of a focussed window
        history % the history of changes in the cleaned GPS data, the selection of points, and saving the data. To be used for undo/redo functionality
        history_current_idx % keeps track of which version of history is presented
        mode = 'focus'; % whether the current mode is in 'focus'  or 'all', determining the current visualization
        originalobject % the original data plot, later used for handling the brush
        selectedartifact % index of selected artifact
        time % float value for time
        timeResolution % distance between two timepoints
        visibleArtifactsRectangles % rectangles mapped on the axis
        visibleartifacts % array of 1s (true) and 0s (false) to keep track of which artifacts are visible
    end
    
    
    methods (Access = private)
        %% CALLBACKS

        % Callback when artifact is clicked on (NOTE: this is a public function in the app)
        function ClickArtifact(comp, object, eventdata)

        end
        
        % Callback when original data axis is clicked on
        function ClickAxes(comp, object, eventdata)

        end

        %% INTERACTIVITY


        % Returns to the previous state of cleaned GPS and selection
        function UndoAction(comp)
            % make sure there history to go back to
            if comp.history_current_idx > 1
                % retrun to the previous state
                comp.history_current_idx = comp.history_current_idx - 1;
                comp.GPSdata_clean       = comp.history(comp.history_current_idx).GPSdata_clean;
                comp.GPSdata_selected    = comp.history(comp.history_current_idx).GPSdata_selected;
                comp.UnsavedChanges      = ~comp.history(comp.history_current_idx).Saved;
                % now enable undoing the undo action
                comp.RedoButton.Enable   = true;
            end
            
            % if there is no more history left
            if comp.history_current_idx <= 1
                % disable the undo button
                comp.UndoButton.Enable = false;
            end

            comp.SetActiveUIElements();
            comp.PlotVisuals();
        end
        
        % Returns to a state of cleaned GPS and selection that was perviously undone
        function RedoAction(comp)
            % make sure there history to go back to
            if length(comp.history) > comp.history_current_idx
                % retrun to the next state
                comp.history_current_idx = comp.history_current_idx + 1;
                comp.GPSdata_clean       = comp.history(comp.history_current_idx).GPSdata_clean;
                comp.GPSdata_selected    = comp.history(comp.history_current_idx).GPSdata_selected;
                comp.UnsavedChanges      = ~comp.history(comp.history_current_idx).Saved;
                % so there is a state to go back to
                comp.UndoButton.Enable   = true;
            end
            
            % if there is no more undone history to go back to
            if comp.history_current_idx >= length(comp.history)
                % disable the redo button
                comp.RedoButton.Enable = false;
            end

            comp.SetActiveUIElements();
            comp.PlotVisuals();
        end
        
        % add current state to the history list and enable the undo button
        function AddActionToHistory(comp)
            % if there were history states that were undone, these should now be removed
            if length(comp.history) > comp.history_current_idx
                comp.history(comp.history_current_idx+1:end) = [];
            end
            % keep track of which version of history is being shown
            comp.history_current_idx = comp.history_current_idx + 1;
            % add the cleaned GPS data 
            comp.history(comp.history_current_idx).GPSdata_clean = comp.GPSdata_clean;
            % add the selected GPS data
            comp.history(comp.history_current_idx).GPSdata_selected = comp.GPSdata_selected;
            % this state has not been saved yet
            comp.history(comp.history_current_idx).Saved = false;
            % if there is at least one state to return to
            if comp.history_current_idx > 1
                comp.UndoButton.Enable = true; 
            end
            % but redo is now not possible anymore
            comp.RedoButton.Enable   = false;
            comp.SetActiveUIElements();
        end


        % Show the entire data signal
        function ShowAllData(comp)
            comp.mode = 'all';
            
            % since the map has changed, remove the 'near data' marker
            comp.cursorIsNearData = false;

            % set axes range
            min_lon = min(comp.GPSdata_clean.lon) - 0.001;
            min_lat = min(comp.GPSdata_clean.lat) - 0.001;
            max_lon = max(comp.GPSdata_clean.lon) + 0.001;
            max_lat = max(comp.GPSdata_clean.lat) + 0.001;
            geolimits(comp.GPSAxes, [min_lat max_lat], [min_lon max_lon]);

        end


        %% VISUALS
        % Reset the current visuals, and draw the latest visual state
        function PlotVisuals(comp)
            % updating the map while the animation is running causes
            % problems. The Axes is cleared, so the animiation dots are
            % deleted. Quick fix for now is to prevent changing the map while the animation is running
            % TODO: Implement a better solution
            if ~comp.AnimationIsRunning
                comp.PlotArtifactValidation();
            end
        end

         % Draw the Artifact validation area 
        function PlotArtifactValidation(comp)

            % clear the graph area
            cla(comp.GPSAxes);

            % set the map type
            geobasemap(comp.GPSAxes, comp.cfg.basemap);

            % hold on = draw over the graph, keep what is already there
            hold(comp.GPSAxes,'on');

            % draw the artifact intervals
            comp.PlotArtifacts();

            % draw the original data
            geoplot(comp.GPSAxes, comp.GPSdata_raw.lat, comp.GPSdata_raw.lon,'LineStyle', ":", 'LineWidth', 1, 'Color', "k");

            % monitor whether the graph area is clicked on
            set(comp.GPSAxes,'ButtonDownFcn',@comp.ClickAxes);

            % draw the clean data
            geoplot(comp.GPSAxes, comp.GPSdata_clean.lat, comp.GPSdata_clean.lon, LineStyle = "--", LineWidth = 1, Color = "g", MarkerSize = 5, MarkerEdgeColor="g", Marker="o");

            
            % draw the zoom rectangle
            comp.drawZoomRectangle();           
            
            % draw the selection rectangle
            comp.drawSelectionRectangle();       

            % highlight the selected datapoints
            BUasOrange =  [0.9294 0.4588 0.1294];
            geoplot(comp.GPSAxes, comp.GPSdata_clean.lat(comp.GPSdata_selected), comp.GPSdata_clean.lon(comp.GPSdata_selected), LineStyle = 'none', MarkerSize = 8, MarkerFaceColor = BUasOrange, MarkerEdgeColor = 'white', Marker='o');

            % highlight datapoints that are near the cursor
            if comp.cursorIsNearData
                comp.HoverOverDataDot = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.GPSdata_near_cursor), comp.GPSdata_clean.lon(comp.GPSdata_near_cursor), 80, 'white', 'filled', 'o', MarkerEdgeColor = 'black');
            end
            
            if comp.UnsavedChanges
                comp.GPSAxes.Title.String = [comp.cfg.segment_identifier '*'];
            else
                comp.GPSAxes.Title.String = comp.cfg.segment_identifier;
            end

            % hold off
            hold(comp.GPSAxes,'off');

            
        end


        function drawZoomRectangle(comp)
            if comp.ZoomRectangleVisible
                % Create and plot polygon
                lat1 = comp.ZoomRectangleStart(1, 1);
                lon1 = comp.ZoomRectangleStart(1, 2);
                lat2 = comp.ZoomRectangleEnd(1, 1);
                lon2 = comp.ZoomRectangleEnd(1, 2);

                poly = geopolyshape([lat1 lat2 lat2 lat1 lat1], [lon1 lon1 lon2 lon2 lon1]);
                BUasBlue = [0, 0.2510, 0.4196];
                comp.ZoomRectangle = geoplot(comp.GPSAxes, poly, 'FaceColor',  BUasBlue, 'FaceAlpha', 0.2, 'EdgeColor', BUasBlue);
                % comp.ZoomRectangle.ShapeData = poly;
                % comp.ZoomRectangle.Visible ='on';
            end
        end

        % zoom the GPS axes to the rectangle that was drawn
        function zoomToRectangle(comp)
            
            % hide the zoom rectangle
            comp.ZoomRectangleVisible = false;

            % MapCenter is the middle point of the rectangle that was drawn
            newMapCenterLat = (comp.ZoomRectangleStart(1, 1) + comp.ZoomRectangleEnd(1, 1)) / 2;
            newMapCenterLon = (comp.ZoomRectangleStart(1, 2) + comp.ZoomRectangleEnd(1, 2)) / 2;

            % for readability
            ax = comp.GPSAxes;

            % adjust the zoom level
            oldRangeLat = ax.LatitudeLimits(2) - ax.LatitudeLimits(1);
            oldRangeLon = ax.LongitudeLimits(2) - ax.LongitudeLimits(1);
            zoomRectangleRangeLat = abs(comp.ZoomRectangleEnd(1, 1) - comp.ZoomRectangleStart(1, 1));
            zoomRectangleRangeLon = abs(comp.ZoomRectangleEnd(1, 2) - comp.ZoomRectangleStart(1, 2));
            zoomFactorLat = oldRangeLat / zoomRectangleRangeLat;
            zoomFactorLon = oldRangeLon / zoomRectangleRangeLon;
            zoomFactor = min([zoomFactorLat, zoomFactorLon]);
            newZoomLevel = ax.ZoomLevel + log2(zoomFactor);
            if newZoomLevel > 25 % cannot exceed 25
                newZoomLevel = 25;
            end
            ax.ZoomLevel = newZoomLevel;

            % adjust the MapCenter
            ax.MapCenter(1) = newMapCenterLat;
            ax.MapCenter(2) = newMapCenterLon;

            % since the map has changed, remove the 'near data' marker
            comp.cursorIsNearData = false;

            % redraw
            % TODO: this is only needed to hide the zoom rectangle
            % change that to a named rectangle that can be deleted
            comp.PlotVisuals();

        end

        % select all datapoints that are inside the rectangle that was drawn
        function selectDatapointsInRectangle(comp)
            % get the lat and lon dimensions of the rectangle
            minLat = min(comp.SelectionRectangleStart(1, 1), comp.SelectionRectangleEnd(1, 1));
            maxLat = max(comp.SelectionRectangleStart(1, 1), comp.SelectionRectangleEnd(1, 1));
            minLon = min(comp.SelectionRectangleStart(1, 2), comp.SelectionRectangleEnd(1, 2));
            maxLon = max(comp.SelectionRectangleStart(1, 2), comp.SelectionRectangleEnd(1, 2));

            % find all datapoints within these boundaries
            for data_i = 1:length(comp.GPSdata_clean.lat)
                if and(isbetween(comp.GPSdata_clean.lat(data_i), minLat, maxLat), ...
                        isbetween(comp.GPSdata_clean.lon(data_i), minLon, maxLon))
                    if strcmp(comp.SelectionType, 'extend')
                        % add the new selection to the existing one
                        comp.GPSdata_selected(data_i) = true;
                        % update the selection sequence
                        comp.addSelectedIdxToSequence(data_i);
                    elseif strcmp(comp.SelectionType, 'subtract')
                        % deselect the points in the selection rectangle
                        comp.GPSdata_selected(data_i) = false;
                        % update the selection sequence
                        comp.removeSelectedIdxFromSequence(data_i);
                    end
                end

            end

            % update the history for undo/redo functionality
            comp.AddActionToHistory()

            % redraw
            comp.PlotVisuals();

        end


        % add selected datapoint to the selection sequence
        function addSelectedIdxToSequence(comp, data_array)
            % first remove that index from the sequence to acoid duplicates
            removeSelectedIdxFromSequence(comp, data_array);
            % then add the index to the end of the sequence
            comp.GPSdata_selected_datapoint_idx(end + 1:end + length(data_array)) = data_array;
        end


        % add selected datapoint to the selection sequence
        function removeSelectedIdxFromSequence(comp, data_array)
            for i=1:length(data_array) 
                data_idx = data_array(i);
                sequence_idx = find(comp.GPSdata_selected_datapoint_idx, data_idx);
                if ~isempty(sequence_idx)
                    comp.GPSdata_selected_datapoint_idx(sequence_idx) = [];
                end
            end
        end

        % if datapoints are in the selection sequence, remove them
        % if they are not, add them
        function reverseSelectedIdxFromSequence(comp, data_array)
            for i=1:length(data_array) 
                data_idx = data_array(i);
                sequence_idx = find(comp.GPSdata_selected_datapoint_idx, data_idx);
                if isempty(sequence_idx)
                    %  add the index to the end of the sequence
                    comp.GPSdata_selected_datapoint_idx(end + 1) = data_idx;
                else
                    % remove the datapoints from the sequence
                    comp.GPSdata_selected_datapoint_idx(sequence_idx) = [];
                end
            end
            
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
        end


        function drawSelectionRectangle(comp)
            if comp.SelectionRectangleVisible
                % Create and plot polygon
                lat1 = comp.SelectionRectangleStart(1, 1);
                lon1 = comp.SelectionRectangleStart(1, 2);
                lat2 = comp.SelectionRectangleEnd(1, 1);
                lon2 = comp.SelectionRectangleEnd(1, 2);

                poly = geopolyshape([lat1 lat2 lat2 lat1 lat1], [lon1 lon1 lon2 lon2 lon1]);
                BUasOrange =  [0.9294 0.4588 0.1294];
                geoplot(comp.GPSAxes, poly, 'FaceColor', BUasOrange, 'FaceAlpha', 0.2, 'EdgeColor',  BUasOrange)
            end
        end

        
        % Plot all rectangles where artifacts are suspected
        function PlotArtifacts(comp)
            for i=find(comp.visibleartifacts) % loop through the visible artifacts
                artifact_circle_width = 0.004;
                artifact_circle_left = comp.artifacts(i).lon - artifact_circle_width/2;
                artifact_circle_bottom = comp.artifacts(i).lat - artifact_circle_width/2;
                color = [.8 .8 .8 0.2];
                if comp.selectedartifact == i
                    color = [1 .8 .8 0.3];
                end
                comp.visibleArtifactsRectangles(i) = rectangle(comp.GPSAxes,'Position',[artifact_circle_left artifact_circle_bottom artifact_circle_width artifact_circle_width],"FaceColor",color,'Curvature',[1 1]);
                set(comp.visibleArtifactsRectangles(i),'ButtonDownFcn',@comp.ClickArtifact);
             end
        end


        %% UI ELEMENTS

        % Set the enabled UI elements based on the selected artifacts
        function SetActiveUIElements(comp)

            % % undo button
            % if (length(comp.history)) > 1
            %     comp.RedoButton.Enable = 1;
            % else
            %     comp.RedoButton.Enable = 0;                
            % end
            % % focus, previous, next
            % if ~isempty(comp.artifacts)
            %     comp.SelectAllButton.Enable = 1;
            %     comp.SelectNoneButton.Enable = 1;
            % else
            %     comp.SelectAllButton.Enable = 0;
            %     comp.SelectNoneButton.Enable = 0;
            % end
            % if (comp.selectedartifact <= 1)
            %     comp.SelectAllButton.Enable = 0;
            % end
            
        end

        % Disable the stop|skip|save buttons
        function DisableStopSkipSaveButtons(comp)
            comp.StopButton.Enable = "off";
            comp.NextButton.Enable = "off";
            comp.SaveButton.Enable = "off";
        end

        
        % replace selected data points by NaNs
        function DeleteSelection(comp)

            % replace the selected data with NaNs
            comp.GPSdata_clean.lat(comp.GPSdata_selected) = NaN;
            comp.GPSdata_clean.lon(comp.GPSdata_selected) = NaN;

            % deselect the removed data points
            comp.GPSdata_selected_datapoint_idx = [];
            comp.GPSdata_selected(comp.GPSdata_selected)  = false;

            % data has changed
            comp.UnsavedChanges = true;
            
            % update the history for undo/redo functionality
            comp.AddActionToHistory();

            % redraw
            comp.PlotVisuals();

        end

        % inerpolate selected data points where possible, replace by NaNs when interpolation is not possible
        function InterpolateSelection(comp)

            % split selected data into cluster os selected data points
            % first, find the indices where the selection changes from false to true (diff = 1) or the other way (diff = -1)
            d = diff(comp.GPSdata_selected);
            cluster_indices = [find([comp.GPSdata_selected(1);d]==1) find([d;-comp.GPSdata_selected(end)]==-1)];

            % for each cluster 
            for cluster_i = 1:size(cluster_indices, 1)
                % check whether there is a non-selected datapoint both before and after
                start_idx = cluster_indices(cluster_i, 1);
                end_idx   = cluster_indices(cluster_i, 2);
                if (    start_idx == 1  || ...                              % first datapoint was selected
                        end_idx == length(comp.GPSdata_raw.lat) || ...      % last datapoint was selected
                        isnan(comp.GPSdata_clean.lat(start_idx - 1)) || ... % the point before the selection is a NaN
                        isnan(comp.GPSdata_clean.lat(end_idx + 1)) ...      % the point after the selection is a NaN
                    )
                    
                    % interpolation not possible, replace by NaNs
                    comp.GPSdata_clean.lat(start_idx:end_idx) = NaN;
                    comp.GPSdata_clean.lon(start_idx:end_idx) = NaN;
                    comp.GPSdata_selected(start_idx:end_idx) = false;
                else

                    % interpolate the selcted points
                    % get the coordinates of the point before and after
                    % use the cleaned data as anchors
                    before_lat = comp.GPSdata_clean.lat(start_idx - 1);
                    before_lon = comp.GPSdata_clean.lon(start_idx - 1);
                    after_lat  = comp.GPSdata_clean.lat(end_idx   + 1);
                    after_lon  = comp.GPSdata_clean.lon(end_idx   + 1);
                    
                    % get the number of selected points
                    nof_selected_points = end_idx - start_idx + 1;

                    % Interpolate along a great circle
                    [interpolated_lat, interpolated_lon] = gcwaypts(before_lat,before_lon,after_lat,after_lon,nof_selected_points-1); %interpm(range_lat, range_lon, nof_selected_points, 'gc');

                    % asign lat, lon values
                    comp.GPSdata_clean.lat(start_idx:end_idx) = interpolated_lat;
                    comp.GPSdata_clean.lon(start_idx:end_idx) = interpolated_lon;
                end

            end

            % all selected data points have been handled
            comp.GPSdata_selected_datapoint_idx = [];
            
            % data has changed
            comp.UnsavedChanges = true;
            
            % update the history for undo/redo functionality
            comp.AddActionToHistory();

            % redraw
            comp.PlotVisuals();

        end


    end
    
    methods (Access = public)
        
        % clear the data from the axes
        function ClearData(comp)
            comp.GPSAxes.Title.String = '';
            comp.GPSdata_raw = [];
            comp.GPSdata_clean = [];
            comp.GPSdata_selected = [];
            comp.GPSdata_selected_datapoint_idx = [];
            % set UI Elements
            comp.SetActiveUIElements();
            % clear the graph area
            cla(comp.GPSAxes);
            % set initial axes
            % comp.PlotVisuals();
            pause(0.2); % wait 0.2 second to show that the previous data has been closed

        end
        
        % show the GPS data
        function showData(comp, cfg, raw_data, cleaned_data)

            comp.cfg = cfg; % keep track of the cfg, we want to pass it back to the app

            % just in case no pp_label and segment name was provided
            if ~isfield(cfg, 'segment_identifier')
                comp.cfg.segment_identifier = 'Warning: unknown segment';
            end


            % the GPS data 
            comp.GPSdata_raw = raw_data; % the raw data
            comp.GPSdata_clean = cleaned_data; % the cleaned data

            % a list of boleans to keep track of which datapoints are selected
            comp.GPSdata_selected = false(size(raw_data.lat));

            % new data, so no unsaved changes
            comp.UnsavedChanges = false;

            comp.ShowAllData();


            % enable the stop|skip|save buttons
            comp.StopButton.Enable = "on";
            comp.NextButton.Enable = "on";
            comp.SaveButton.Enable = "on";

            % clear the history
            comp.history(:) = [];
            comp.history_current_idx = 0;
            comp.UndoButton.Enable = false;
            comp.RedoButton.Enable = false;
            % set the loaded state as a history starting point
            comp.AddActionToHistory();
            comp.history(comp.history_current_idx).Saved = true;
            
            % set UI Elements
            comp.SetActiveUIElements();
            % set initial axes
            comp.PlotVisuals();
        end
        

        % update the basemap
        function updateBasemap(comp, basemapType)
            comp.cfg.basemap = basemapType;
            comp.PlotVisuals();
        end

        % Handle mouse movements. This function is called by the parent app
        % input parameter is the cursor position
        % output is the type of mouse pointer
        function Pointertype = MouseMove(comp, cursorXY)

            Pointertype = "arrow";

            % only when the data is ready
            if ~isfield(comp.GPSdata_clean, 'lat')
                return;
            end
            
            % for readability
            ax = comp.GPSAxes;


            % GeoAxes position measures are normalized by default, switching to pixels here
            ax.Units = 'pixels';

            % Get the Axis coordinates
            GPSAxes_left   = ax.InnerPosition(1);
            GPSAxes_right  = GPSAxes_left + ax.InnerPosition(3);
            GPSAxes_bottom = ax.InnerPosition(2);
            GPSAxes_top    = GPSAxes_bottom + ax.InnerPosition(4);

            % determine whether mouse is over the GPS data area
            isInAxes = isbetween(cursorXY(1), GPSAxes_left, GPSAxes_right) && ...
                       isbetween(cursorXY(2), GPSAxes_bottom, GPSAxes_top);

            if isInAxes
                % when the mouse is moving over the GPS axes, one of these things is happening
                % 1) the mouse is not near a datapoint -> nothing to do, just moving around
                % 2) when the mouse is near a datapont -> highlight that point: size, colour
                % 3) if a zoom box is being dragged -> show the rectangle from the start until the current point
                % 4) if a datapoint is being dragged -> show the resulting data: line and markers

                % get the GPS coordinates of the current cursor position
                cursorPos = ax.CurrentPoint;

                % for debugging
                % comp.GPSAxes.Title.String = sprintf("lat = %d, lon = %d", cursorGPS(1, 1), cursorGPS(1, 2));

                % if a selected datapoint is in the process of being dragged
                if strcmp(comp.GPSAxesDragging, 'datapoint')
                    % update the position of the selected datapoint

                elseif strcmp(comp.GPSAxesDragging, 'zoom')

                    % Update the end location of the zoom rectangle
                    comp.ZoomRectangleEnd = cursorPos;

                    % redraw the map
                    comp.ZoomRectangleVisible = true;
                    comp.PlotVisuals();

                elseif strcmp(comp.GPSAxesDragging, 'select')

                    % Update the end location of the zoom rectangle
                    comp.SelectionRectangleEnd = cursorPos;

                    % redraw the map
                    comp.SelectionRectangleVisible = true;
                    comp.PlotVisuals();

                elseif strcmp(comp.GPSAxesDragging, 'map')
                    % MapCenter should move with the cursor

                    % calculate the dragging distance in XY pixels
                    CursorMovedX = cursorXY(1) - comp.DragMapStartXY(1);
                    CursorMovedY = cursorXY(2) - comp.DragMapStartXY(2);

                    % divide by the width/height of the GeoAxes and multiply by the width/height in latitude/longitude
                    CursorMovedLat = CursorMovedY / ax.InnerPosition(4) * (ax.LatitudeLimits(2) - ax.LatitudeLimits(1));
                    CursorMovedLon = CursorMovedX / ax.InnerPosition(3) * (ax.LongitudeLimits(2) - ax.LongitudeLimits(1));
                    
                    % Shift the center of the map by those amounts in the opposite direction
                    MapCenterLat = comp.DragMapStartMapCenter(1) - CursorMovedLat;
                    MapCenterLon = comp.DragMapStartMapCenter(2) - CursorMovedLon;
                    ax.MapCenter = [MapCenterLat, MapCenterLon];


                else % no dragging is going on

                    % test whether the cursor is near a datapoint
                    isNearData = false;
                    nearnessLimit = 10/2^comp.GPSAxes.ZoomLevel; % consider only points within this distance from the cursor. 

                    % get the lat and lon dimensions of the nearness range
                    cursorLat = cursorPos(1, 1);
                    cursorLon = cursorPos(1, 2);
                    minLat = cursorLat - nearnessLimit;
                    maxLat = cursorLat + nearnessLimit;
                    minLon = cursorLon - nearnessLimit;
                    maxLon = cursorLon + nearnessLimit;
            

                    % find all datapoints within these boundaries
                    PointsThatAreNear = find(and(isbetween(comp.GPSdata_clean.lat, minLat, maxLat), ...
                                isbetween(comp.GPSdata_clean.lon, minLon, maxLon)));
                    nof_PointsThatAreNear = length(PointsThatAreNear);
                    distToCursor = inf(size(PointsThatAreNear));
                    for data_i = 1:nof_PointsThatAreNear
                        data_idx = PointsThatAreNear(data_i);

                            % calculate the distance to the cursor    
                            wgs84 = wgs84Ellipsoid("m"); % Using WGS84 ellipsoid (GPS) with unit meters
                            distToCursor(data_i) = distance(...
                                comp.GPSdata_clean.lat(data_idx), comp.GPSdata_clean.lon(data_idx), ...
                                cursorLat, cursorLon, wgs84);

                            % note that the cursor is near data
                            isNearData = true;
                    end

                    % skip the rest if the cursor is not near data
                    if isNearData
                        % start fresh

                        % get the shortest distance
                        shortestDist = min(distToCursor);

                        % store all points with this shortest distance
                        comp.GPSdata_near_cursor = PointsThatAreNear(distToCursor(:) < shortestDist + 0.1); % everything with a 10cm distance
                        comp.HoverOverDataDotLat = comp.GPSdata_clean.lat(comp.GPSdata_near_cursor(end));
                        comp.HoverOverDataDotLon = comp.GPSdata_clean.lon(comp.GPSdata_near_cursor(end));

                        % highlight datapoints that are near the cursor
                        if comp.cursorIsNearData
                            % there is already a dot, now move it to the current position
                            set(comp.HoverOverDataDot, 'LatitudeData', comp.HoverOverDataDotLat, 'LongitudeData',  comp.HoverOverDataDotLon);
                            drawnow limitrate; % Limit refresh rate            
                        else
                            comp.cursorIsNearData = true;
                            hold(comp.GPSAxes,'on');
                            comp.HoverOverDataDot = geoscatter(comp.GPSAxes, comp.HoverOverDataDotLat, comp.HoverOverDataDotLon, 80, 'white', 'filled', 'o', MarkerEdgeColor = 'black');
                            hold(comp.GPSAxes,'off');
                        end
                    else
                        % check if we need to remove the 'near cursor' marker
                        if comp.cursorIsNearData
                             comp.cursorIsNearData = false;
                             delete(comp.HoverOverDataDot);
                        end

                    end
                end

            else % the cursor has moved out of the map

                % stop dragging 
                comp.GPSAxesDragging = 'none'; 
            end
        
        end % MouseMove()


        % mouse up event is passed on by the parent app
        function MouseUp(comp, cursorXY)


            % Check if some kind of dragging was going on
            if strcmp(comp.GPSAxesDragging, 'zoom')

                % zoom to map to the rectangle
                comp.zoomToRectangle();
                
            elseif strcmp(comp.GPSAxesDragging, 'select')

                % hide the zoom rectangle
                comp.SelectionRectangleVisible = false;

                % select all datapoints that are within that rectangle
                comp.selectDatapointsInRectangle();
                
            elseif strcmp(comp.GPSAxesDragging, 'datapoint')
                % drop the datapoint at the current position
                %TODO add functionality to restore a dragged datapoint to its original position

             elseif strcmp(comp.GPSAxesDragging, 'map')
                % moving the map is handled by the MouseMove function so nothing to do
                % but if the map was not moved, this was just a click on the map
                % and all points should be deselected
                if comp.DragMapStartXY == cursorXY
                    comp.GPSdata_selected = false(size(comp.GPSdata_clean.lat));
                    comp.GPSdata_selected_datapoint_idx = [];
                    comp.GPSAxesDragging = 'none';
                    comp.PlotVisuals();
                end

           end

            % if the Shift key is pressed
            if comp.ShiftKeyDown

            end

            % stop any dragging functionality
            comp.GPSAxesDragging = 'none';

        end


        % mouse down event is passed on by the parent app
        function MouseDown(comp, cursorXY, selectionType)

            % for readability
            ax = comp.GPSAxes;

            % GeoAxes position measures are normalized by default, switching to pixels here
            ax.Units = 'pixels';

            % Get the Axis coordinates
            GPSAxes_left   = ax.InnerPosition(1);
            GPSAxes_right  = GPSAxes_left + ax.InnerPosition(3);
            GPSAxes_bottom = ax.InnerPosition(2);
            GPSAxes_top    = GPSAxes_bottom + ax.InnerPosition(4);

            % determine wheter mouse is over the GPS data area
            isInAxes = isbetween(cursorXY(1), GPSAxes_left, GPSAxes_right) && ...
                       isbetween(cursorXY(2), GPSAxes_bottom, GPSAxes_top);

            if isInAxes
                % for readability
                ax = comp.GPSAxes;
    
                % was it a left or a right mouse click
                % we have to circumvent the normal MatLab handling here
                % Click left         => SelectionType = 'normal'
                % Click left + Shift => SelectionType = 'extend'
                % Click left + Ctrl  => SelectionType = 'alt'
                % Click right        => SelectionType = 'alt'
                % Double click any   => SelectionType = 'open'
                % So we deduce a right click from SelectionType = 'alt' and Ctrl was not pressed
                if and(strcmp(selectionType, 'alt'), ~comp.ControlKeyDown)
                    buttonType = 'right';
                elseif  strcmp(selectionType, 'open') 
                    buttonType = 'double';
                elseif strcmp(selectionType, 'normal') 
                    buttonType = 'left';
                else
                    buttonType = 'left';
                end

                % if the mousebutton is pressed while the cursus was near/on a datapoint
                % select those data points
                if comp.cursorIsNearData
                     if comp.ShiftKeyDown
                         % if there is already a selected data point
                         if ~isempty(comp.GPSdata_selected_datapoint_idx)
                             % add to the selection and all points in between the last selected point
                             % get the data index of the last selected data point
                             last_selected_idx = comp.GPSdata_selected_datapoint_idx(end);
                             % get the current idx that is clicked on
                             % this is likely an array of points, choose the one that is furthest away from the last
                             % selected point
                             data_idx_array = comp.GPSdata_near_cursor;
                             if last_selected_idx > max(data_idx_array)
                                 data_idx = min(data_idx_array);
                                 comp.GPSdata_selected(data_idx:last_selected_idx-1) = true;
                             else
                                 data_idx = max(data_idx_array);
                                 comp.GPSdata_selected(last_selected_idx+1:data_idx) = true;
                            end
                            comp.GPSdata_selected_datapoint_idx = data_idx_array;
                         else % if there is no selected data point, simply select the current one
                             % select the 'near cursor' data points, deselect all others
                             comp.GPSdata_selected = false(size(comp.GPSdata_clean.lat));
                             comp.GPSdata_selected(comp.GPSdata_near_cursor) = true;
                             comp.GPSdata_selected_datapoint_idx = comp.GPSdata_near_cursor;
                         end
                     elseif comp.ControlKeyDown
                         % reverse the selection of the 'near cursor' data points                          
                         comp.GPSdata_selected(comp.GPSdata_near_cursor) = ~comp.GPSdata_selected(comp.GPSdata_near_cursor);
                         comp.reverseSelectedIdxFromSequence(comp.GPSdata_near_cursor);
                     else
                         % select the 'near cursor' data points, deselect all others
                         comp.GPSdata_selected = false(size(comp.GPSdata_clean.lat));
                         comp.GPSdata_selected(comp.GPSdata_near_cursor) = true;
                         comp.GPSdata_selected_datapoint_idx = comp.GPSdata_near_cursor;
                     end

                    % selection has changed, update history
                    comp.AddActionToHistory();
                    % redraw
                    % comp.cursorIsNearData = false;
                    comp.GPSAxesDragging = 'none';
                    comp.PlotVisuals();
                else
                    if strcmp(buttonType, 'right')
                        % start drawing a zoom rectangle
                        comp.GPSAxesDragging = 'zoom';
                        comp.ZoomRectangleStart = ax.CurrentPoint;
    
                    elseif strcmp(buttonType, 'left')
                        if comp.ShiftKeyDown
                            % start drawing a selection rectangle
                            comp.GPSAxesDragging = 'select';
                            comp.SelectionType = 'extend';
                            comp.SelectionRectangleStart = ax.CurrentPoint;
    
                        elseif comp.ControlKeyDown
                            % start drawing a selection rectangle
                            comp.GPSAxesDragging = 'select';
                            comp.SelectionType = 'subtract';
                            comp.SelectionRectangleStart = ax.CurrentPoint;
    
                        else
                            % start moving the map
                            comp.GPSAxesDragging = 'map';
                            comp.DragMapStartXY = cursorXY;
                            comp.DragMapStartMapCenter = ax.MapCenter;
                        end
                    end
                end
            end
            
        end

        % mouse scrollwheel event is passed on by the parent app
        function ScrollWheel(comp, VerticalScrollCount)
            
            % for readability
            ax = comp.GPSAxes;

            % get the new zoom level
            newZoomLevel = comp.GetNewZoomLevel(VerticalScrollCount);

            % calculate the change in zoomLevel
            % ZoomLevel is logarithmic: increase by 1 doubles the scale
            zoomChange = newZoomLevel - ax.ZoomLevel;
            zoomChangeFactor = 2^zoomChange;

            % adjust the MapCenter so that zooming keeps the map fixed at the cursor location
            % get the cursor position
            cursorLat = ax.CurrentPoint(1,1);
            cursorLon = ax.CurrentPoint(1,2);
            % get the MapCenter position
            mapCenterLat = ax.MapCenter(1);
            mapCenterLon = ax.MapCenter(2);
            % get the map width and height
            mapLatMin  = ax.LatitudeLimits(1);
            mapLatMax  = ax.LatitudeLimits(2);
            mapLatDiff = mapLatMax - mapLatMin;
            mapLonMin  = ax.LongitudeLimits(1);
            mapLonMax  = ax.LongitudeLimits(2);
            mapLonDiff = mapLonMax - mapLonMin;
            % calculate the cursor position as a percentage of the map
            percLat = (cursorLat - mapLatMin) / mapLatDiff;
            percLon = (cursorLon - mapLonMin) / mapLonDiff;
            % calculate the new lat/lon differences from cursor to mapcenter
            newMapLatDiff = mapLatDiff / zoomChangeFactor;
            newMapLonDiff = mapLonDiff / zoomChangeFactor;
            newMapLatMin = mapCenterLat - newMapLatDiff / 2;
            newMapLonMin = mapCenterLon - newMapLonDiff / 2;
            % the new cursor lat/lon would then be
            newCursorLat = newMapLatMin + percLat * newMapLatDiff;
            newCursorLon = newMapLonMin + percLon * newMapLonDiff;
            % but we want to keep the lat/lon the same for the cursor
            % so we will move the map
            newMapCenterLat = mapCenterLat + (cursorLat - newCursorLat);
            newMapCenterLon = mapCenterLon + (cursorLon - newCursorLon);            

            % set the new zoom level
            ax.ZoomLevel = newZoomLevel;

            % adjust the MapCenter
            ax.MapCenter(1) = newMapCenterLat;
            ax.MapCenter(2) = newMapCenterLon;

        end

        function newZoomLevel = GetNewZoomLevel(comp, zoomDirection)

            % for readability
            ax = comp.GPSAxes;

            % zoom faster if the Shift key is pressed
            % VerticalScrollCount = -1 or 1
            if comp.ShiftKeyDown
                ZoomChange = 1 * zoomDirection * -1;
            else
                ZoomChange = 0.2 * zoomDirection * -1;
            end

            % calculate the new zoom level
            newZoomLevel = ax.ZoomLevel + ZoomChange;

            % check ZoomLevel limits
            if newZoomLevel < 2 % Offical limit is 0, but location drifts for small numbers
                newZoomLevel = 2;
            elseif newZoomLevel > 25 % Offical limit is 25
                newZoomLevel = 25;
            end

        end

        function MoveMap(comp, direction)
            % TODO: if the focus is on the participant bookkeeping table,
            %       the arrow keys move the focus to another cell and trigger it's action.
            %       Will need to look for a way to prevent that.

            % for readability
            ax = comp.GPSAxes;

            % move faster if the Shift key is pressed
            if comp.ShiftKeyDown
                MoveAmount = 0.001;
            else
                MoveAmount = 0.0002;
            end

            switch direction
                case 'left' % move in longitudinal direction
                    ax.MapCenter(2) = ax.MapCenter(2) + MoveAmount; 
                case 'right' % move in longitudinal direction
                    ax.MapCenter(2) = ax.MapCenter(2) - MoveAmount;
                case 'up' % move in latitudinal direction
                    ax.MapCenter(1) = ax.MapCenter(1) - MoveAmount;
                case 'down' % move in latitudinal direction
                    ax.MapCenter(1) = ax.MapCenter(1) + MoveAmount;
            end

        end

        % keypress is passed on by the parent app
        function KeyPress(comp, Key)
            switch Key 
                case 'shift'
                    comp.ShiftKeyDown = true;
                case 'control'
                    comp.ControlKeyDown = true;
                case 'add' % plus sign -> zoom in
                    comp.ZoomInButtonPushed();
                case 'subtract' % minus sign -> zoom out
                    comp.ZoomOutButtonPushed();
                case 'space'  % zoom to show all data
                    comp.ZoomHomeButtonPushed();
                case 'leftarrow'  % move the map to the left
                    comp.MoveMap('left');
                case 'rightarrow'  % move the map to the right
                    comp.MoveMap('right');
                case 'uparrow'  % move the map up
                    comp.MoveMap('up');
                case 'downarrow'  % move the map down
                    comp.MoveMap('down');
                case 'delete'  % delete or interpolate selected data
                    if comp.ShiftKeyDown
                        comp.DeleteSelection();
                    else
                        comp.InterpolateSelection();
                    end
                        
            end
        end

        % keyrelease is passed on by the parent app
        function KeyRelease(comp, Key)
            switch Key 
                case 'shift'
                    comp.ShiftKeyDown = false;
                case 'control'
                    comp.ControlKeyDown = false;
            end
        end


%% =========      Animation functions     ============================
        function showGPSAnimation(comp)

            % stop abd delete leftover animation timers
            listOfTimers = timerfindall;
            if ~isempty(listOfTimers)
                stop(listOfTimers(:));
                delete(listOfTimers(:));
            end            

            % start the animation at this position
            if isempty(comp.GPSdata_selected_datapoint_idx)
                comp.AnimationIndex = ones(1, 10);
            else
                comp.AnimationIndex = ones(1, 10) * comp.GPSdata_selected_datapoint_idx(end);
            end
            comp.AnimationCounter = 1;
            comp.AnimationIsRunning = true;
            comp.AnimationStatus = 'Playing';


            % Show the current position of the GPS animation
            BUasOrange =  [0.9294 0.4588 0.1294]; % orange
            hold(comp.GPSAxes,'on');
            comp.AnimationDot(10) = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(10)), comp.GPSdata_clean.lon(comp.AnimationIndex(10)),  60, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.4,  'MarkerFaceAlpha',0.4);
            comp.AnimationDot(9)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(9)),  comp.GPSdata_clean.lon(comp.AnimationIndex(9)),   60, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.4,  'MarkerFaceAlpha',0.4);
            comp.AnimationDot(8)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(8)),  comp.GPSdata_clean.lon(comp.AnimationIndex(8)),   60, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.4,  'MarkerFaceAlpha',0.4);
            comp.AnimationDot(7)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(7)),  comp.GPSdata_clean.lon(comp.AnimationIndex(7)),   70, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.45, 'MarkerFaceAlpha',0.45);
            comp.AnimationDot(6)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(6)),  comp.GPSdata_clean.lon(comp.AnimationIndex(6)),   80, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.5,  'MarkerFaceAlpha',0.5);
            comp.AnimationDot(5)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(5)),  comp.GPSdata_clean.lon(comp.AnimationIndex(5)),   90, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.6,  'MarkerFaceAlpha',0.6);
            comp.AnimationDot(4)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(4)),  comp.GPSdata_clean.lon(comp.AnimationIndex(4)),  100, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.7,  'MarkerFaceAlpha',0.7);
            comp.AnimationDot(3)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(3)),  comp.GPSdata_clean.lon(comp.AnimationIndex(3)),  110, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.8,  'MarkerFaceAlpha',0.8);
            comp.AnimationDot(2)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(2)),  comp.GPSdata_clean.lon(comp.AnimationIndex(2)),  130, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 0.9,  'MarkerFaceAlpha',0.9);
            comp.AnimationDot(1)  = geoscatter(comp.GPSAxes, comp.GPSdata_clean.lat(comp.AnimationIndex(1)),  comp.GPSdata_clean.lon(comp.AnimationIndex(1)),  150, BUasOrange, 'filled', 'o', 'MarkerEdgeColor', 'white', 'MarkerEdgeAlpha', 1.0,  'MarkerFaceAlpha',1.0);
            hold(comp.GPSAxes,'off');


            % create a new timer
            t = timer('Name', 'AnimationTimer');

            % set call back functions
            t.TimerFcn = @comp.AnimationTimerUpdate; % after each period
            t.StopFcn  = @comp.AnimationTimerStop; % when timer stops

            % specify timing properties
            t.period = 0.001; % seconds
            t.StartDelay = 0.001;
            t.TasksToExecute = length(comp.GPSdata_clean.lat); % number of times the update function is called
            t.ExecutionMode = 'singleShot'; % this means the TimerFcn happens only once, then the StopFcn is called. This gives us the opportunity to change the speed or pause. Then we start the timer again.

            % start the timer
            t.start;        
        end        
      
        function AnimationTimerUpdate(comp, mTimer, ~)
            % Show the current position of the GPS animation
            comp.ShowAnimation(1);            
        end
        
        function AnimationTimerStop(comp, mTimer, ~)
            % adjust the speed of the animation
            delayArray = [1.0, 0.5, 0.1, 0.05, 0.01, 0.005, 0.001];
            mTimer.StartDelay = delayArray(comp.AnimationSpeed);            
            % check whether the pause button was pressed
            % check for the end of the data
            if comp.AnimationIndex(1) >= length(comp.GPSdata_clean.lat)
                % end reached, start over from start
                % comp.AnimationIndex = ones(1, 10);
                % comp.AnimationCounter = 1;
                % or pause
                comp.AnimationStatus = 'Paused';
            end
            switch comp.AnimationStatus
                case  'Stopped'
                    stop(mTimer);
                    delete(mTimer);
                    comp.AnimationIsRunning = false;
                    comp.PlotVisuals();
                case 'Paused'
                    % wait until it starts again
                case 'Playing'
                    % restart the timer
                    start(mTimer);
            end
           
        end

        function AnimationStop(comp)
            % stop the timer
             comp.AnimationIsRunning = false;
             comp.AnimationStatus = 'Stopped';
        end

        function ShowAnimation(comp, stepSize)
            % check whether the GPS animation is running
                % increase the dot counters
                for counter_i = 1:comp.AnimationCounter
                    % increase the index by the provided step size
                    comp.AnimationIndex(counter_i) = comp.AnimationIndex(counter_i) + stepSize;
                    % keep the index within bounds
                    if comp.AnimationIndex(counter_i) > length(comp.GPSdata_clean.lat)
                        comp.AnimationIndex(counter_i) = length(comp.GPSdata_clean.lat);
                    elseif comp.AnimationIndex(counter_i) < 1
                        comp.AnimationIndex(counter_i) = 1;
                    end
                end
                % increase the animation counter, if possible
                comp.AnimationCounter = min(comp.AnimationCounter+1, length(comp.AnimationDot));
                % draw the animation dots
                set(comp.AnimationDot(10), 'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(10)), 'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(10)));
                set(comp.AnimationDot(9),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(9)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(9)));
                set(comp.AnimationDot(8),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(8)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(8)));
                set(comp.AnimationDot(7),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(7)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(7)));
                set(comp.AnimationDot(6),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(6)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(6)));
                set(comp.AnimationDot(5),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(5)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(5)));
                set(comp.AnimationDot(4),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(4)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(4)));
                set(comp.AnimationDot(3),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(3)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(3)));
                set(comp.AnimationDot(2),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(2)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(2)));
                set(comp.AnimationDot(1),  'LatitudeData', comp.GPSdata_clean.lat(comp.AnimationIndex(1)),  'LongitudeData', comp.GPSdata_clean.lon(comp.AnimationIndex(1)));
                
                drawnow limitrate; % Limit refresh rate            


        end


    end % methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % make sure that openstreetsmap is ready
            basemapName = "openstreetmap";
            url = "a.tile.openstreetmap.org/${z}/${x}/${y}.png"; 
            copyright = char(uint8(169));
            attribution = copyright + "OpenStreetMap contributors";
            addCustomBasemap(basemapName,url,Attribution=attribution, IsDeployable=true);

            % Create geographic axes
            comp.GPSAxes = geoaxes(comp.GeoAxesGridLayout, ...
                NextPlot = "add", ...
                Basemap = "streets-light", ...
                MapCenter = [51.590548764358566, 4.795605396225078], ...
                ZoomLevel = 15);            
            
            ax =  comp.GPSAxes;
            % Plot a location pin at BUas Experience Lab
            BUasOrange =  [0.9294 0.4588 0.1294];
            geoplot(ax, 51.590548764358566, 4.795605396225078, 'Color', BUasOrange, 'Marker', "*", 'MarkerSize', 10, 'LineWidth', 2);
            % no interactions
            ax.Interactions = []; %[panInteraction zoomInteraction];
            % hide the axes toolbar (save, paint, hand, zoom, home)
            ax.Toolbar.Visible = 'off';            
            % Add title
            ax.Title.String = "BUas Experience Lab";
            % make the axes as big as possible
            % TODO: I cannot get the axes bigger, this does not work
            % outerpos = ax.OuterPosition;
            % ti = ax.TightInset; 
            % left = outerpos(1) + ti(1) - 0.1;
            % bottom = outerpos(2) + ti(2) -0.1;
            % ax_width = outerpos(3) - ti(1) - ti(3) + 0.2;
            % ax_height = outerpos(4) - ti(2) - ti(4) + 0.2; 
            % ax.Position = [left bottom ax_width ax_height];
            ax.Position = [-0.2 -0.2 1.4 1.4];


            % note that no dragging is currently going on
            comp.GPSAxesDragging = 'none'; 

        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(comp, event)
            % select each data point
            %TODO: how to handle NaN position points?
            comp.GPSdata_selected(:) = true;
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: SelectNoneButton
        function SelectNoneButtonPushed(comp, event)
            % deselect each data point
            comp.GPSdata_selected(:) = false;
            comp.GPSdata_selected_datapoint_idx = [];
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: RestoreAllButton
        function RestoreAllButtonPushed(comp, event)
            % restore all of the clean data back to the original data
            comp.GPSdata_clean = comp.GPSdata_raw;
            % note that the data has changed
            comp.UnsavedChanges = true;            
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: RestoreSelectionButton
        function RestoreSelectionButtonPushed(comp, event)
            % restore the selected clean data back to the original data
            comp.GPSdata_clean.lat(comp.GPSdata_selected) = comp.GPSdata_raw.lat(comp.GPSdata_selected);
            comp.GPSdata_clean.lon(comp.GPSdata_selected) = comp.GPSdata_raw.lon(comp.GPSdata_selected);
            % note that the data has changed
            comp.UnsavedChanges = true;
            
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
            % redraw
            comp.PlotVisuals();
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(comp, event)
            % for readability
            ax = comp.GPSAxes;
            % get the new zoom level
            zoomDirection = -1; % zoom in
            newZoomLevel = comp.GetNewZoomLevel(zoomDirection);
            % set the new zoom level
            ax.ZoomLevel = newZoomLevel;
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(comp, event)
            % for readability
            ax = comp.GPSAxes;
            % get the new zoom level
            zoomDirection = 1; % zoom out
            newZoomLevel = comp.GetNewZoomLevel(zoomDirection);
            % set the new zoom level
            ax.ZoomLevel = newZoomLevel;
        end

        % Button pushed function: ZoomHomeButton
        function ZoomHomeButtonPushed(comp, event)
            comp.ShowAllData();
            comp.PlotVisuals();
        end

        % Button pushed function: DeleteSelectionButton
        function DeleteSelectionButtonPushed(comp, event)
            % replace the selected data with NaNs
            comp.DeleteSelection();
        end

        % Button pushed function: InterpolateSelectionButton
        function InterpolateSelectionButtonPushed(comp, event)
            % interpolate the selected data if possible, otherwise replace with NaNs
            comp.InterpolateSelection();
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(comp, event)
            comp.UndoAction();            
        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(comp, event)
            comp.RedoAction();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(comp, event)
            notify(comp, 'StopCleaning');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(comp, event)
            if comp.UnsavedChanges
                % ask whether changes need to be saved or discarded
                dlgtitle = 'Unsaved changes';
                question = sprintf('You have made changes to the data that are not yet saved.\nWould you like me to save the changes?');
                opts.Default = 'Save';
                question_answer = questdlg(question, dlgtitle, 'Save','Discard', opts.Default);
                % Handle response
                switch question_answer
                    case 'Save'
                        % Save the changes and open the next dataset
                        notify(comp, 'SaveAndNextCleaning');
                    case 'Discard'
                        % Discard changes and open the next dataset
                        notify(comp, 'NextCleaning');
                end
                % for some reason the question dialog shifts focus to a
                % random window, so give focus back to the app
                figure(app.UIFigure);
            else
                % All changes are saved, open the next dataset
                notify(comp, 'NextCleaning');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(comp, event)
            % disable the stop, skip, and save buttons
            % comp.DisableStopSkipSaveButtons();
            % Let the BELT app know what happened
            notify(comp, 'SaveCleanedData');
            % just saved, so no unsaved changes
            comp.UnsavedChanges = false;
            % update the history
            for hist_i = 1:comp.history_current_idx
                comp.history(hist_i).Saved = false;
            end
            comp.history(comp.history_current_idx).Saved = true;
            % update the graph
            comp.PlotVisuals();
        end

        % Button pushed function: JumpsButton
        function JumpsButtonPushed(comp, event)
            % Find and select all GPS points that have a jump that is larger than the specified limit
            % read the limit value
            jump_limit = comp.JumpLimit.Value;
            % calculate the distances from every point to the next.
            wgs84 = wgs84Ellipsoid("m"); % Using WGS84 ellipsoid (GPS) with unit meters
            % get the number of GPS points
            nof_gps_points = length(comp.GPSdata_raw.lat);
            % the distance with the previous point is 0 for the first point
            comp.GPSdata_raw.dist(1) = 0;
            % for each point calculate the distance with the previous point
            comp.GPSdata_raw.dist(2:nof_gps_points) = distance(...
                comp.GPSdata_raw.lat(1:nof_gps_points-1), ...
                comp.GPSdata_raw.lon(1:nof_gps_points-1), ...
                comp.GPSdata_raw.lat(2:nof_gps_points), ...
                comp.GPSdata_raw.lon(2:nof_gps_points), wgs84);
            % select all points for which the distance exceeds that limit
            comp.GPSdata_selected = comp.GPSdata_raw.dist > jump_limit;
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
            % show the selection
            comp.PlotVisuals();

        end

        % Button pushed function: AccuracyButton
        function AccuracyButtonPushed(comp, event)
            % Find and select all GPS points that have an accuracy value that is higher than the specified limit
            % read the limit value
            accuracy_limit = comp.AccuracyLimit.Value;
            % select all points for which the accuracy exceeds that limit
            comp.GPSdata_selected = comp.GPSdata_raw.acc > accuracy_limit;
            % update the history for undo/redo functionality
            comp.AddActionToHistory()
            % show the selection
            comp.PlotVisuals();

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(comp, event)
            switch comp.AnimationStatus
                case 'Paused'
                    % resume the paused animation
                    comp.AnimationStatus = 'Playing';
                    t = timerfind("Name",'AnimationTimer');
                    t(1).start;
                case 'Playing'
                    % nothing to do
                otherwise
                    % start the animation
                    comp.showGPSAnimation();
            end
        end

        % Button pushed function: StopAnimationButton
        function StopAnimationButtonPushed(comp, event)
            comp.AnimationStatus = 'Stopped';
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(comp, event)
            if strcmp(comp.AnimationStatus, 'Paused')
                comp.AnimationStatus = 'Playing';
                t = timerfind("Name",'AnimationTimer');
                t(1).start;
            else
                comp.AnimationStatus = 'Paused';
            end
        end

        % Button pushed function: StepBackwardButton
        function StepBackwardButtonPushed(comp, event)
            if strcmp(comp.AnimationStatus, 'Paused')
                if comp.ShiftKeyDown
                    comp.ShowAnimation(-10);
                else
                    comp.ShowAnimation(-1);
                end
            end
        end

        % Button pushed function: StepForwardButton
        function StepForwardButtonPushed(comp, event)
            if strcmp(comp.AnimationStatus, 'Paused')
                if comp.ShiftKeyDown
                    comp.ShowAnimation(10);
                else
                    comp.ShowAnimation(1);
                end
            end

        end

        % Button pushed function: FasterButton
        function FasterButtonPushed(comp, event)
            if comp.AnimationSpeed < 7
                comp.AnimationSpeed = comp.AnimationSpeed + 1;
                comp.SlowerButton.Enable = 'on';
                if comp.AnimationSpeed == 7
                    comp.FasterButton.Enable = 'off';
                end
            end
        end

        % Button pushed function: SlowerButton
        function SlowerButtonPushed(comp, event)
            if comp.AnimationSpeed > 1
                comp.AnimationSpeed = comp.AnimationSpeed - 1;
                comp.FasterButton.Enable = 'on';
                if comp.AnimationSpeed == 1
                    comp.SlowerButton.Enable = 'off';
                end
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1028 598];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.Tooltip = {''};
            comp.SelectionPanel = uipanel(comp.GridLayout2);
            comp.SelectionPanel.Title = 'Selection';
            comp.SelectAllButton = uibutton(comp.SelectionPanel, 'push');
            comp.SelectAllButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectAllButtonPushed, true);
            comp.SelectAllButton.Tooltip = {'Select all data points'};
            comp.SelectAllButton.Text = 'Select all';
            comp.SelectNoneButton = uibutton(comp.SelectionPanel, 'push');
            comp.SelectNoneButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SelectNoneButtonPushed, true);
            comp.SelectNoneButton.Tooltip = {'Deselect all '};
            comp.SelectNoneButton.Text = 'Select none';
            comp.RestoreSelectionButton = uibutton(comp.SelectionPanel, 'push');
            comp.RestoreSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreSelectionButtonPushed, true);
            comp.RestoreSelectionButton.Tooltip = {'Restore all selected data points to their original position'};
            comp.RestoreSelectionButton.Text = 'Restore selection';
            comp.RestoreAllButton = uibutton(comp.SelectionPanel, 'push');
            comp.RestoreAllButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreAllButtonPushed, true);
            comp.RestoreAllButton.Tooltip = {'Restore all data points to their original position'};
            comp.RestoreAllButton.Text = 'Restore all';
            comp.ConclusionPanel = uipanel(comp.GridLayout2);
            comp.ConclusionPanel.Title = 'Conclusion';
            comp.GridLayout6 = uigridlayout(comp.ConclusionPanel);
            comp.NextButton = uibutton(comp.GridLayout6, 'push');
            comp.NextButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NextButtonPushed, true);
            comp.NextButton.Icon = fullfile(pathToMLAPP, 'icons', 'skipWhite.png');
            comp.NextButton.Enable = 'off';
            comp.NextButton.Tooltip = {'Go to the next participant'};
            comp.NextButton.Text = '';
            comp.SaveButton = uibutton(comp.GridLayout6, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButtonPushed, true);
            comp.SaveButton.Icon = fullfile(pathToMLAPP, 'icons', 'saveWhite.png');
            comp.SaveButton.Enable = 'off';
            comp.SaveButton.Tooltip = {'Save clean GPS data'};
            comp.SaveButton.Text = '';
            comp.StopButton = uibutton(comp.GridLayout6, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopButtonPushed, true);
            comp.StopButton.Icon = fullfile(pathToMLAPP, 'icons', 'stopWhite.png');
            comp.StopButton.Enable = 'off';
            comp.StopButton.Tooltip = {'Stop GPS cleaning'};
            comp.StopButton.Text = '';
            comp.ActionsPanel_2 = uipanel(comp.GridLayout2);
            comp.ActionsPanel_2.Title = 'Actions';
            comp.GridLayout5 = uigridlayout(comp.ActionsPanel_2);
            comp.RedoButton = uibutton(comp.GridLayout5, 'push');
            comp.RedoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RedoButtonPushed, true);
            comp.RedoButton.Icon = fullfile(pathToMLAPP, 'icons', 'redoWhite.png');
            comp.RedoButton.Tooltip = {'Redo last action'};
            comp.RedoButton.Text = '';
            comp.UndoButton = uibutton(comp.GridLayout5, 'push');
            comp.UndoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UndoButtonPushed, true);
            comp.UndoButton.Icon = fullfile(pathToMLAPP, 'icons', 'undoWhite.png');
            comp.UndoButton.Tooltip = {'Undo last action'};
            comp.UndoButton.Text = '';
            comp.InterpolateSelectionButton = uibutton(comp.GridLayout5, 'push');
            comp.InterpolateSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @InterpolateSelectionButtonPushed, true);
            comp.InterpolateSelectionButton.Icon = fullfile(pathToMLAPP, 'icons', 'interpolateWhite.png');
            comp.InterpolateSelectionButton.Tooltip = {'Interpolate selected points (Del)'};
            comp.InterpolateSelectionButton.Text = '';
            comp.DeleteSelectionButton = uibutton(comp.GridLayout5, 'push');
            comp.DeleteSelectionButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DeleteSelectionButtonPushed, true);
            comp.DeleteSelectionButton.Icon = fullfile(pathToMLAPP, 'icons', 'deleteWhite.png');
            comp.DeleteSelectionButton.Tooltip = {'Delete selected points (Shift+Del)'};
            comp.DeleteSelectionButton.Text = '';
            comp.ZoomPanel = uipanel(comp.GridLayout2);
            comp.ZoomPanel.Title = 'Zoom';
            comp.GridLayout4 = uigridlayout(comp.ZoomPanel);
            comp.ZoomHomeButton = uibutton(comp.GridLayout4, 'push');
            comp.ZoomHomeButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomHomeButtonPushed, true);
            comp.ZoomHomeButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoomHomeWhite.png');
            comp.ZoomHomeButton.Tooltip = {'Zoom to fit all (Space)'};
            comp.ZoomHomeButton.Text = '';
            comp.ZoomOutButton = uibutton(comp.GridLayout4, 'push');
            comp.ZoomOutButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomOutButtonPushed, true);
            comp.ZoomOutButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoomOutWhite.png');
            comp.ZoomOutButton.Tooltip = {'Zoom out (-)'};
            comp.ZoomOutButton.Text = '';
            comp.ZoomInButton = uibutton(comp.GridLayout4, 'push');
            comp.ZoomInButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ZoomInButtonPushed, true);
            comp.ZoomInButton.Icon = fullfile(pathToMLAPP, 'icons', 'zoomInWhite.png');
            comp.ZoomInButton.Tooltip = {'Zoom in (+)'};
            comp.ZoomInButton.Text = '';
            comp.FindPanel = uipanel(comp.GridLayout2);
            comp.FindPanel.Title = 'Find';
            comp.JumpsButton = uibutton(comp.FindPanel, 'push');
            comp.JumpsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @JumpsButtonPushed, true);
            comp.JumpsButton.Tooltip = {'Select all data points'};
            comp.JumpsButton.Text = 'Jumps >';
            comp.AccuracyButton = uibutton(comp.FindPanel, 'push');
            comp.AccuracyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AccuracyButtonPushed, true);
            comp.AccuracyButton.Tooltip = {'Deselect all '};
            comp.AccuracyButton.Text = 'Accuracy >';
            comp.Label = uilabel(comp.FindPanel);
            comp.Label.Text = 'meter';
            comp.JumpLimit = uieditfield(comp.FindPanel, 'numeric');
            comp.JumpLimit.Limits = [0 200];
            comp.JumpLimit.ValueDisplayFormat = '%.0f';
            comp.JumpLimit.Value = 25;
            comp.meterEditField_2Label = uilabel(comp.FindPanel);
            comp.meterEditField_2Label.Text = 'meter';
            comp.AccuracyLimit = uieditfield(comp.FindPanel, 'numeric');
            comp.AccuracyLimit.Limits = [0 200];
            comp.AccuracyLimit.ValueDisplayFormat = '%.0f';
            comp.AccuracyLimit.Value = 25;
            comp.AnimatePanel = uipanel(comp.GridLayout2);
            comp.AnimatePanel.Title = 'Animate';
            comp.GridLayout3 = uigridlayout(comp.AnimatePanel);
            comp.StepBackwardButton = uibutton(comp.GridLayout3, 'push');
            comp.StepBackwardButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StepBackwardButtonPushed, true);
            comp.StepBackwardButton.Icon = fullfile(pathToMLAPP, 'icons', 'rewindWhiteLarge.png');
            comp.StepBackwardButton.Tooltip = {'Step forward'};
            comp.StepBackwardButton.Text = '';
            comp.PauseButton = uibutton(comp.GridLayout3, 'push');
            comp.PauseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PauseButtonPushed, true);
            comp.PauseButton.Icon = fullfile(pathToMLAPP, 'icons', 'pauseWhiteLarge.png');
            comp.PauseButton.Tooltip = {'Pause animation'};
            comp.PauseButton.Text = '';
            comp.StopAnimationButton = uibutton(comp.GridLayout3, 'push');
            comp.StopAnimationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopAnimationButtonPushed, true);
            comp.StopAnimationButton.Icon = fullfile(pathToMLAPP, 'icons', 'stopWhiteLarge.png');
            comp.StopAnimationButton.Tooltip = {'Stop animation'};
            comp.StopAnimationButton.Text = '';
            comp.PlayButton = uibutton(comp.GridLayout3, 'push');
            comp.PlayButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PlayButtonPushed, true);
            comp.PlayButton.Icon = fullfile(pathToMLAPP, 'icons', 'playWhiteLarge.png');
            comp.PlayButton.Tooltip = {'Play animation'};
            comp.PlayButton.Text = '';
            comp.StepForwardButton = uibutton(comp.GridLayout3, 'push');
            comp.StepForwardButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StepForwardButtonPushed, true);
            comp.StepForwardButton.Icon = fullfile(pathToMLAPP, 'icons', 'fastforwardWhiteLarge.png');
            comp.StepForwardButton.Tooltip = {'Step back'};
            comp.StepForwardButton.Text = '';
            comp.FasterButton = uibutton(comp.GridLayout3, 'push');
            comp.FasterButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FasterButtonPushed, true);
            comp.FasterButton.Icon = fullfile(pathToMLAPP, 'icons', 'plusWhiteSmall.png');
            comp.FasterButton.Tooltip = {'Faster'};
            comp.FasterButton.Text = '';
            comp.SlowerButton = uibutton(comp.GridLayout3, 'push');
            comp.SlowerButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SlowerButtonPushed, true);
            comp.SlowerButton.Icon = fullfile(pathToMLAPP, 'icons', 'minusWhiteSmall.png');
            comp.SlowerButton.Tooltip = {'Slower'};
            comp.SlowerButton.Text = '';
            comp.GeoAxesGridLayout = uigridlayout(comp.GridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end